classdef ControlTurbina < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MenMenu                    matlab.ui.container.Menu
        NuevoensayoMenu            matlab.ui.container.Menu
        GuardardatosMenu           matlab.ui.container.Menu
        ConfiguracinMenu           matlab.ui.container.Menu
        AcercadeMenu               matlab.ui.container.Menu
        GridLayout2                matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        GraficosPrimarios          matlab.ui.container.Tab
        GridGraficosPrimarios      matlab.ui.container.GridLayout
        BotonAmplia5               matlab.ui.control.StateButton
        BotonAmplia4               matlab.ui.control.StateButton
        BotonAmplia3               matlab.ui.control.StateButton
        BotonAmplia2               matlab.ui.control.StateButton
        BotonAmplia1               matlab.ui.control.StateButton
        GraficoFlujoCombustible    matlab.ui.control.UIAxes
        GraficoRpm                 matlab.ui.control.UIAxes
        GraficoEmpuje              matlab.ui.control.UIAxes
        GraficoPresion             matlab.ui.control.UIAxes
        GraficoTemperatura         matlab.ui.control.UIAxes
        GrficossecundariosTab      matlab.ui.container.Tab
        GridGraficosSecundarios    matlab.ui.container.GridLayout
        BotonAmplia11              matlab.ui.control.StateButton
        BotonAmplia10              matlab.ui.control.StateButton
        BotonAmplia9               matlab.ui.control.StateButton
        BotonAmplia8               matlab.ui.control.StateButton
        BotonAmplia7               matlab.ui.control.StateButton
        BotonAmplia6               matlab.ui.control.StateButton
        GraficoValvGas             matlab.ui.control.UIAxes
        GraficoPotBomba            matlab.ui.control.UIAxes
        GraficoThrottle            matlab.ui.control.UIAxes
        GraficoVoltajeStarter      matlab.ui.control.UIAxes
        GraficoVoltajeBat          matlab.ui.control.UIAxes
        GraficoValvQuero           matlab.ui.control.UIAxes
        TelemetraPanel             matlab.ui.container.Panel
        GridMetricas               matlab.ui.container.GridLayout
        TextoTiempoTranscurrido    matlab.ui.control.Label
        TextoTiempoMuestreo        matlab.ui.control.Label
        MetricasSecundarias        matlab.ui.control.Label
        ComunicacionPanel          matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        ArrancadoParadoLabel       matlab.ui.control.Label
        LuzArrancado               matlab.ui.control.Lamp
        ParadaButton               matlab.ui.control.Button
        ArranqueButton             matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        ApagadodeemergenciaButton  matlab.ui.control.StateButton
        TextoComandos              matlab.ui.control.Label
        BotonEnviarControlManual   matlab.ui.control.Button
        CampoControlManual         matlab.ui.control.EditField
        BotonDatosArriba           matlab.ui.control.Button
        BotonDatosAbajo            matlab.ui.control.Button
        BotonMenuArriba            matlab.ui.control.Button
        BotonMenuAbajo             matlab.ui.control.Button
        EstadoConexion             matlab.ui.control.Label
        Conectar                   matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        PantallaHDT2               matlab.ui.control.Label
        PantallaHDT                matlab.ui.control.Label
    end

    properties (Access = private)
          
            %Comunicacion
            ComArduino;
            ComDSCUSB;
            
            %Variables de control
            EstaArrancado = 0;
            EstaParando = 0;
            NuevoEnsayo = 0;
            TemperaturaParada = 80;
            ContadorErrores = 0; 
            
            %Variables de datos
            Temperaturas = [];TemperaturaActual;
            RPMs = [];RPMActual;
            PotenciasBomba = [];PotenciaBombaActual;
            Throttles = [];ThrottleActual;            
            VoltajesBateria =[];VoltajeBateriaActual;   
            Tiempo = [];TiempoTranscurrido;
            TiempoInicial = -1;TiempoActual;
            
            %Variables de sensores
            Presiones=[];PresionActual;
            ValvGas=[];ValvGasActual;
            ValvQuero=[];ValvQueroActual;
            VoltajesStarter=[];VoltajeStarterActual;
            FlujosCombustible=[];FlujoCombustibleActual;
            Empujes=[];EmpujeActual;
            EstadosBujia=[];EstadoBujiaActual;
            
            %Datos recibidos
            DatosRecibidos;
            TextoHDT = "";
            Telemetria = "";
            DatosSensores = "";
            DatosRecibidosDSCUSB;
            
            %Animaciones
            AnimacionRPM;
            AnimacionTemperatura;
            AnimacionPotenciaBomba;
            AnimacionThrottle;
            AnimacionVoltaje;
            
            AnimacionPresion;
            AnimacionValvGas;
            AnimacionValvQuero;
            AnimacionVoltajeStarter
            AnimacionFlujoCombustible;
            AnimacionEmpuje;
            
            %Directorios
            DirectorioIconos = addpath(genpath('Icons'));
            
            %Valores graficos globales
            RangoX;
    
            %Valores grafico rpm
            RangoYRPM;            
            ValorLineaRPM;
            EstiloLineaRPM;
            
            %Valores grafico temperatura
            RangoYTemp;
            ValorLineaTemp;
            EstiloLineaTemp;
            
            %Valores grafico potencia bomba
            RangoYPotBomb;
            ValorLineaPotBomb;
            EstiloLineaPotBomb;            
            
            %Valores grafico throttle
            RangoYThrottle;
            ValorLineaThrottle;
            EstiloLineaThrottle; 
            
            %Valores grafico voltaje
            RangoYVoltaje;
            ValorLineaVoltaje;
            EstiloLineaVoltaje;
            
            %Valores grafico valvula gas
            RangoYValvGas;
            
            %Valores grafico valvula queroseno
            RangoYValvQuero;
            
            %Valores grafico presion
            RangoYPresion;
            ValorLineaPresion;
            EstiloLineaPresion; 
            
            %Valores grafico caudal combustible
            RangoYCombustible;
            ValorLineaCombustible;
            EstiloLineaCombustible; 
            
            %Valores grafico starter
            RangoYStarter;
            ValorLineaStarter;
            EstiloLineaStarter;
            
            %Valores grafico Empuje
            RangoYEmpuje;
            ValorLineaEmpuje;
            EstiloLineaEmpuje;
            
    end    
    
    methods (Access = private)
        function Conecta(app)
            app.EstadoConexion.Text = "Conectando...";
            app.EstadoConexion.FontColor = [0,0,0]; 
            
            try 
                %Configuramos el puerto del Arduino
                app.ComArduino = serialport("COM10",115200);
            
            catch
                %Si no se consigue conectar el Arduino se informa del
                %error a través del espacio de texto habilitado
                app.EstadoConexion.Text = "Error al conectar con Arduino";
                app.EstadoConexion.FontColor = [1,0,0];
                
                %Volvemos a intentar conectar con Arduino
                try
                    app.ComArduino = [];
                    app.ComArduino = serialport("COM10",115200);
                
                catch
                end
                
             end
            
            try
            %Configuramos el puerto del DSCUSB
            app.ComDSCUSB = serialport("COM11",115200);
            %Configuramos el final de carrera para la trama de datos del
            %DSCUSB
            configureTerminator(app.ComDSCUSB,"CR");
            
            catch
                %Si no se consigue conectar el DSCUSB se informa del
                %error a través del espacio de texto habilitado
                app.EstadoConexion.Text = "Error al conectar con DSCUSB";
                app.EstadoConexion.FontColor = [1,0,0];
                
                try
                    %Volvemos a intentar conectar con DSCUSB
                    app.ComDSCUSB = [];
                    app.ComDSCUSB = serialport("COM11",115200);
                    configureTerminator(app.ComDSCUSB,"CR");
                
                catch
                end
                
            end
            
            %Taramos DSCUSB
            TaraDSCUSB(app);
            
            %Leemos los datos del Arduino y DSCUSB
            LeeDatos(app);
            
            %Volvemos a confirmar conexión con Arduino 
            if (~isempty(app.DatosRecibidos))
                %Informamos de que el Arduino esta correctamente conectado
                app.EstadoConexion.Text = "Arduino conectado";
                app.EstadoConexion.FontColor = [0,1,0];
                %Inicializamos el bucle principal
                BuclePrincipal(app);
                
            else
                %Si detectamos algún fallo de conexión lo mostramos por
                %pantalla
                app.EstadoConexion.Text = "Error al conectar con Arduino";
                app.EstadoConexion.FontColor = [1,0,0];
                
            end 
            
        end
        
        function TaraDSCUSB(app)
            %Tomamos varios valores de referencia mediante la función
            %writeread, con la que mandamos al DSCUSB el comando "!001:CELL?"
            Referencia = 0;NValores = 20;
            for i = 1:1:NValores
                Referencia = Referencia + str2double(char(writeread(app.ComDSCUSB,'!001:CELL?')));
                %Pausamos entre muestra y muestra para obtener una mayor
                %dispersión de las muestras
                pause(0.1);
                
            end
            
            %Haciendo uso de la función writeline, mandamos el valor de
            %referencia al DSCUSB haciendo uso del comando "!001:SZ=(Aquí va 
            %el valor de referencia), esto coloca el cero absoluto del sensor 
            %en nuestro valor de referencia"
            writeline(app.ComDSCUSB,append('!001:SZ=',num2str(Referencia/NValores)));
            pause(0.2);
            
            %Borramos los datos del serial para corregir problemas con el
            %final de carro (por alguna razón introduce dos finales de
            %carro al utilizar el comando anterior).
            flush(app.ComDSCUSB); 
            
        end
        
        
        function BuclePrincipal(app)
           %Bucle infinito del programa (igual que un void loop de Arduino)
           while(1==1)
                %Leemos los datos de los diferentes dispositivos
                LeeDatos(app);
                %Representamos los diferentes datos que se han obtenido
                RepresentaDatos(app);

           end
           
        end
        
        function LeeDatos(app)
            %Contador para resetear errores de conexión
            app.ContadorErrores = app.ContadorErrores + 1;
            try 
                %Leemos los datos que manda arduino a traves del puerto
                %serie con readline
                app.DatosRecibidos = readline(app.ComArduino);
                
                %Si el contador llega a 3 reseteamos los mensajes de error
                %(es decir si pasan ~3seg y no hay errores, el mensaje de 
                %error se resetea) 
                if app.ContadorErrores == 3
                    app.ContadorErrores = 0;
                    app.EstadoConexion.Text = "Todo OK";
                    app.EstadoConexion.FontColor = [0,1,0];
                    
                end
                
            catch
                %Si se detecta un error en la comunicación con Arduino se
                %muestra por pantalla y se resetea el contador de reset de
                %errores
                app.EstadoConexion.Text = "Error al conectar con Arduino";
                app.EstadoConexion.FontColor = [1,0,0];
                app.ContadorErrores = 0;
                
                %Se intenta volver a conectar con Arduino
                try
                    app.ComArduino = [];
                    app.ComArduino = serialport("COM10",115200);
                catch
                end
                
            end
            
            
            %Leemos cada trama de datos
            LeeFADEC(app);
            LeeHDT(app);
            LeeArduino(app);
            LeeDCSUSB(app);
            
            %Representamos metricas en el cuadro secundario
            RepresentaMetricasSecundarias(app);


        end
        
        function LeeFADEC(app)
            try
            %Extraemos la trama de datos de los sensores del FADEC de la
            %trama que obtenemos del Arduino
            app.Telemetria = extractAfter(app.DatosRecibidos,("Ç,RCV,"));
            TelemetriaTroceada = regexp(app.Telemetria, ',', 'split');
                    
            %Separamos la trama de datos y los alamacenamos en vriables globales
            app.RPMActual = str2double(char(TelemetriaTroceada(2)));
            app.TemperaturaActual = str2double(char(TelemetriaTroceada(3)));
            app.PotenciaBombaActual = str2double(char(TelemetriaTroceada(4)));
            app.VoltajeBateriaActual = str2double(char(TelemetriaTroceada(5)));
            app.ThrottleActual = str2double(char(TelemetriaTroceada(6)));
            
            %Si detectamos algún fallo durante este proceso es debido al
            %FADEC y este se muestra por pantalla
            catch
                app.EstadoConexion.Text = "Error al conectar con FADEC";
                app.EstadoConexion.FontColor = [1,0,0];
                
            end
            
        end

        function LeeArduino(app)
            try
            %Extraemos la trama de datos de los sensores de arduino de la
            %trama que obtenemos del Arduino
            app.DatosSensores = extractBetween(app.DatosRecibidos,("$SEN"),("SEN$"));
            DatosSensoresTrozeados = regexp(app.DatosSensores, ',', 'split');
            
            %Separamos la trama de datos y los alamacenamos en vriables globales
            app.PresionActual = str2double(char(DatosSensoresTrozeados(1)))/1000;
            app.ValvGasActual = str2double(char(DatosSensoresTrozeados(2)));
            app.ValvQueroActual = str2double(char(DatosSensoresTrozeados(3)));
            app.VoltajeStarterActual = str2double(char(DatosSensoresTrozeados(4)));
            app.EstadoBujiaActual = str2double(char(DatosSensoresTrozeados(5)));
            app.FlujoCombustibleActual = str2double(char(DatosSensoresTrozeados(6)));
            
            %Si detectamos algún fallo durante este proceso es debido a un
            %error en la trama de Arduino y este se muestra por pantalla
            catch
                app.EstadoConexion.Text = "Error trama Arduino";
                app.EstadoConexion.FontColor = [1,0,0];
                
            end
            
            
        end
                
        function LeeHDT(app)
            %Extraemos la trama de datos de la pantalla del FADEC(lo que se
            %veria si el HDT estuviese conectado) de la trama de datos de
            %Arduino
            app.TextoHDT = extractBetween(app.DatosRecibidos,("Ç,RSD,"),("Ç,RCV,"));
            app.TextoHDT = char(app.TextoHDT);
            
            try
                %Si todo esta correcto representamos los tados en el HDT
                %virtual
                app.PantallaHDT.Text = string(app.TextoHDT(4:19));
                app.PantallaHDT2.Text = string(app.TextoHDT(20:end)); 
                    
                %Si detectamos algún fallo durante este proceso es debido al
                %FADEC y este se muestra por pantalla
            catch
                app.EstadoConexion.Text = "Error al conectar con FADEC";
                app.EstadoConexion.FontColor = [1,0,0];
                
            end
        
        end
        
        function LeeDCSUSB(app)
            %Extraemos datos del empuje del DCSUSB
                        
             try
                %Mediante writeline enviamos el comando "!001:SYS?" y
                %leemos su respuesta, al trabajar este sensor a compresion
                %se coloca un menos delante de la lectura para invertir el
                %signo del empuje
                app.DatosRecibidosDSCUSB = char(writeread(app.ComDSCUSB,'!001:SYS?'));   
                app.EmpujeActual = -str2double(app.DatosRecibidosDSCUSB);
                
             catch
                %Si no se consigue leer el valor se muestra un mensdaje de 
                %error por pantalla 
                app.EstadoConexion.Text = "Error al conectar con DSCUSB";
                app.EstadoConexion.FontColor = [1,0,0];
                app.ContadorErrores = 0;
                
                try
                 %Intentamos reconectar el DSCUSB, configurando nuevamente 
                 %el final de carrera   
                 app.ComDSCUSB = [];
                 app.ComDSCUSB = serialport("COM11",115200);
                 configureTerminator(app.ComDSCUSB,"CR");
                 
                catch
                end
                 
             end
             
        end
        
        function RepresentaDatos(app)
            %Comprobamos si el motor esta arrancado
            if(app.EstaArrancado == 1)
                
                %Reseteamos variables lógicas y temporales
                if app.NuevoEnsayo == 0
                    app.NuevoEnsayo = 1;
                    app.TiempoInicial = datetime('now');
                    app.TiempoTranscurrido = 0;
                    
                end
                
                %Actualiza eje x
                ActualizaEjes(app);  
                
                %Guardamos en RAM las variables
                BufferVariables(app);
                
                %Actualizamos titulos
                title(app.GraficoRpm,"Rpm = "+string(app.RPMActual)+"rpm");  
                title(app.GraficoTemperatura,"Temperatura = "+string(app.TemperaturaActual)+"ºC");
                title(app.GraficoPresion,"Presión = "+string(app.PresionActual)+"bar");  
                title(app.GraficoEmpuje,"Empuje = "+string(app.EmpujeActual)+"N");  
                title(app.GraficoFlujoCombustible,"Flujo combustible = "+string(app.FlujoCombustibleActual)+"ml/min"); 
                
                title(app.GraficoValvGas,"Válvula gas = "+string(app.ValvGasActual));  
                title(app.GraficoValvQuero,"Válvula queroseno = "+string(app.ValvQueroActual));
                title(app.GraficoVoltajeBat,"Voltaje bateria = "+string(app.VoltajeBateriaActual)+"V");  
                title(app.GraficoVoltajeStarter,"Voltaje starter = "+string(app.VoltajeStarterActual)+"V");  
                title(app.GraficoThrottle,"Throttle = "+string(app.ThrottleActual)+"%");
                title(app.GraficoPotBomba,"Potencia bomba = "+string(app.PotenciaBombaActual)+"W");      
                
                %Actualizamos graficas 
                addpoints(app.AnimacionRPM,app.TiempoTranscurrido,app.RPMActual);
                addpoints(app.AnimacionTemperatura,app.TiempoTranscurrido,app.TemperaturaActual);
                addpoints(app.AnimacionPresion,app.TiempoTranscurrido,app.PresionActual);
                addpoints(app.AnimacionEmpuje,app.TiempoTranscurrido,app.EmpujeActual);
                addpoints(app.AnimacionFlujoCombustible,app.TiempoTranscurrido,app.FlujoCombustibleActual);
    
                addpoints(app.AnimacionValvGas,app.TiempoTranscurrido,app.ValvGasActual);
                addpoints(app.AnimacionValvQuero,app.TiempoTranscurrido,app.ValvQueroActual);
                addpoints(app.AnimacionVoltaje,app.TiempoTranscurrido,app.VoltajeBateriaActual);
                addpoints(app.AnimacionVoltajeStarter,app.TiempoTranscurrido,app.VoltajeStarterActual);
                addpoints(app.AnimacionThrottle,app.TiempoTranscurrido,app.ThrottleActual);                    
                addpoints(app.AnimacionPotenciaBomba,app.TiempoTranscurrido,app.PotenciaBombaActual);
    
                drawnow;
                
                %Vemos si se cumplen las condiciones para parar de
                %representar datos en los gráficos y acabar el ensayo. Como
                %medida de seguridad se seguirán representando y guardando
                %los datos hasta que la temperatura este por debajo de
                %80ºC.
                if ((app.EstaArrancado == 1)&&(app.EstaParando == 1)&&(app.TemperaturaActual<app.TemperaturaParada))
                    app.EstaArrancado = 0;
                    app.EstaParando = 0;
                    app.LuzArrancado.Color = [1,0,0];
                    
                end
                
            end
            
        end
        
        function BufferVariables(app)
            %Obtenemos el tiempo actual
            app.TiempoActual = datetime('now');
            
            %Guardamos datos en variables de memoria (RAM)
            app.RPMs(end+1) = app.RPMActual;
            app.Temperaturas(end+1) = app.TemperaturaActual;
            app.Presiones(end+1) = app.PresionActual;
            app.Empujes(end+1) = app.EmpujeActual;
            app.FlujosCombustible(end+1) = app.FlujoCombustibleActual;
            
            app.ValvGas(end+1) = app.ValvGasActual;
            app.ValvQuero(end+1) = app.ValvQueroActual;
            app.VoltajesBateria(end+1) = app.VoltajeBateriaActual;
            app.VoltajesStarter(end+1) = app.VoltajeStarterActual;    
            app.Throttles(end+1) = app.ThrottleActual;
            app.PotenciasBomba(end+1) = app.PotenciaBombaActual;
            app.EstadosBujia(end+1) = app.EstadoBujiaActual;  
            
            %Calculamos el tiempo transcurrido
            app.TiempoTranscurrido = seconds(app.TiempoActual-app.TiempoInicial);      
            app.Tiempo(end+1) = app.TiempoTranscurrido; 
            
            %Tiempo de muestreo
            if length(app.Tiempo)>1
                app.TextoTiempoTranscurrido.Text = ("Tiempo transcurrido: "+string(app.TiempoTranscurrido)+"s");
                app.TextoTiempoMuestreo.Text = ("Tiempo muestreo: "+string(app.Tiempo(end)-app.Tiempo(end-1))+"s");
                
            end
            
        end
        
        function GuardarDatos(app)
            %Adecuamos variables a guardar (MATLAB no permite guardar 
            % variables de tipo app."" de esta forma)
            Tiempo = app.Tiempo;
            Temperatura = app.Temperaturas;
            RPM = app.RPMs;
            PotenciaBomba = app.PotenciasBomba;
            Throttle = app.Throttles;
            Voltaje = app.VoltajesBateria;
            
            Presion = app.Presiones;
            ValvulaGAS = app.ValvGas;
            ValvulaQueroseno = app.ValvQuero;
            FlujoCombustible = app.FlujosCombustible;
            VoltajeStarter = app.VoltajesStarter;
            Empuje = app.Empujes;
            EstadoBujia = app.EstadosBujia;
            
            %Comprobamos si hay datos en RAM que guardar
            if (~isempty(app.Tiempo))            
                %UI guardado
                [archivo,direccion] = uiputfile(sprintf('TelemetriaTurbina_%s.mat',datestr(now,'yyyy-mm-dd_HH-MM-SS')));

                %Guardamos variables en archivo .mat
                if (direccion ~= 0)
                    save(fullfile(direccion,archivo), ...
                        'Tiempo','RPM','Temperatura','PotenciaBomba','Throttle','Voltaje', ...
                        'Presion','ValvulaGAS',"ValvulaQueroseno","FlujoCombustible",...
                        "VoltajeStarter","Empuje","EstadoBujia");
                end
                
            else
                %Si no hay datos que guardar se avisará por pantalla
                uiconfirm(app.UIFigure,'No hay datos que guardar.','Error de guardado','Options',{'Aceptar'},'Icon','error');
            
            end
        
        end
         
        function RepresentaMetricasSecundarias(app)
            %Creamos texto con las metricas
            Texto = "RPM: "+string(app.RPMActual)+"rpm\n"+...
                    "Temperatura: "+string(app.TemperaturaActual)+"ºC\n"+...
                    "Potencia Bomba: "+string(app.PotenciaBombaActual)+"W\n"+...
                    "Throttle: "+string(app.ThrottleActual)+"\n"+...
                    "Voltaje: "+string(app.VoltajeBateriaActual)+"V\n"+...
                    "Presión: "+string(app.PresionActual)+"Bar\n"+...
                    "Válvula GAS: "+string(app.ValvGasActual)+"  (1=On/0=Off)\n"+...
                    "Válvula QUEROSENO: "+string(app.ValvQueroActual)+"  (1=On/0=Off)\n"+...
                    "Bujía: "+string(app.EstadoBujiaActual)+"  (1=On/0=Off)\n"+...
                    "Voltaje Starter: "+string(app.VoltajeStarterActual)+"V\n"+...
                    "Flujo combustible: "+string(app.FlujoCombustibleActual)+"ml/min\n"+...
                    "Empuje: "+string(app.EmpujeActual)+"N";
            
            %Asignamos nuestra cadena de texto al contenedor de texto
            app.MetricasSecundarias.Text = sprintf(Texto);
            
        end
        
        function CargaConfiguracion(app)
            try 
                %Cargamos la configuración desde el archivo de
                %configuración
                load(fullfile(pwd,'Config.mat'))
                
                %Asignamos los datos cargados a las variables de
                %configuración internas del programa
                app.RangoX = RangoX;
                
                app.RangoYRPM = RangoYRPM;   
                app.ValorLineaRPM = ValorLineaRPM;
                app.EstiloLineaRPM = EstiloLineaRPM;    
                
                app.RangoYTemp = RangoYTemp;   
                app.ValorLineaTemp = ValorLineaTemp; 
                app.EstiloLineaTemp = EstiloLineaTemp;
                
                app.RangoYPotBomb = RangoYPotBomb;   
                app.ValorLineaPotBomb = ValorLineaPotBomb;   
                app.EstiloLineaPotBomb = EstiloLineaPotBomb;
                
                app.RangoYThrottle = RangoYThrottle;  
                app.ValorLineaThrottle = ValorLineaThrottle;  
                app.EstiloLineaThrottle = EstiloLineaThrottle;
                
                app.RangoYVoltaje = RangoYVoltaje; 
                app.ValorLineaVoltaje = ValorLineaVoltaje;   
                app.EstiloLineaVoltaje = EstiloLineaVoltaje;
                
                app.RangoYValvGas = RangoYValvGas;
                app.RangoYValvQuero = RangoYValvQuero;  
                
                app.RangoYPresion = RangoYPresion;  
                app.ValorLineaPresion = ValorLineaPresion;  
                app.EstiloLineaPresion = EstiloLineaPresion;
                
                app.RangoYCombustible = RangoYCombustible;   
                app.ValorLineaCombustible = ValorLineaCombustible;   
                app.EstiloLineaCombustible = EstiloLineaCombustible;
                
                app.RangoYStarter = RangoYStarter; 
                app.ValorLineaStarter = ValorLineaStarter;  
                app.EstiloLineaStarter = EstiloLineaStarter;
                
                app.RangoYEmpuje = RangoYEmpuje;
                app.ValorLineaEmpuje = ValorLineaEmpuje;  
                app.EstiloLineaEmpuje = EstiloLineaEmpuje;
                
            catch
                %Si no se ha podido leer el archgivo de configuración nos
                %saltará un error
                uialert(app.UIFigure,"No se ha podido cargar el archivo de configuracion correctamente. Abra la configuración e introduzca unos parámetros nuevos.","Error","Icon","error"); 
            
            end            
        
        end   
        
        function BorrarDatos(app)
            %Borramos las varibles de datos de la RAM
            app.Tiempo=[];
            
            app.Temperaturas = [];
            app.RPMs = [];
            app.Presiones = [];
            app.Empujes = [];
            app.FlujosCombustible = [];
            
            app.ValvGas=[];
            app.ValvQuero = [];
            app.VoltajesBateria = [];            
            app.VoltajesStarter =[];       
            app.PotenciasBomba = [];  
            app.Throttles = [];
            app.EstadosBujia = [];
            
            %Creamos vector con las variables de los gráficos
            Graficos = [app.GraficoRpm,app.GraficoTemperatura,app.GraficoPresion,...
                app.GraficoEmpuje,app.GraficoFlujoCombustible,app.GraficoValvGas,...
                app.GraficoValvQuero,app.GraficoVoltajeBat,app.GraficoVoltajeStarter,...
                app.GraficoThrottle,app.GraficoPotBomba];
            
            %Borramos tanto la información representada en los gráficos
            %como las lineas auxiliares de estos
            for n = 1:1:length(Graficos)
                Grafico = Graficos(n);
                delete(Grafico.Children);
                
            end
            
        end
        
        function AmpliaGraficosPrimarios(app,numerografico)
            %Ubicamos el grafico en el layout
            fila = numerografico*2-1;
            
            %Cambiamos la altura de las lineas del layout a 0
            app.GridGraficosPrimarios.RowHeight = num2cell(zeros(1,length(app.GridGraficosPrimarios.RowHeight)));
            
            %Cambiamos los tamaños de las dos lineas que componen el
            %espacio del gráfica
            app.GridGraficosPrimarios.RowHeight(fila) = {30};                 
            app.GridGraficosPrimarios.RowHeight(fila+1) ={'1x'};
            
            %Creamos unos vectores con las variables de los gráficos y las
            %animaciones
            Graficos = [app.GraficoRpm,app.GraficoTemperatura,app.GraficoPresion,...
                app.GraficoEmpuje,app.GraficoFlujoCombustible];
            Animaciones = [app.AnimacionRPM,app.AnimacionTemperatura,app.AnimacionPresion,...
                app.AnimacionEmpuje,app.AnimacionFlujoCombustible];
            
            %Recorremos los gráficos que no se van a ampliar para quitar la
            %visibilidad de los gráficos y borrar las lineas
            for n = 1:1:length(Graficos)
                
                if n ~= numerografico
                    Grafico = Graficos(n);
                    Grafico.Visible = 'Off';
                    Animacion = Animaciones(n);
                    Animacion.Visible = 'Off';
                    delete(Grafico.Children(1:end-1));
               
                end
            
            end
            
        end
        
        function ResetGraficosPrimarios(app,numerografico)
            %Restablecemos las alturas de todos las lineas del layout
            for fila = 1:2:length(app.GridGraficosPrimarios.RowHeight)            
                app.GridGraficosPrimarios.RowHeight(fila) = {30};                 
                app.GridGraficosPrimarios.RowHeight(fila+1) ={'1x'};
                
            end
            
            %Creamos unos vectores con las variables de los gráficos y las
            %animaciones            
            Graficos = [app.GraficoRpm,app.GraficoTemperatura,app.GraficoPresion,...
                app.GraficoEmpuje,app.GraficoFlujoCombustible];   
            Animaciones = [app.AnimacionRPM,app.AnimacionTemperatura,app.AnimacionPresion,...
                app.AnimacionEmpuje,app.AnimacionFlujoCombustible];
            
            %Reestablecemos gráficos y volvemos a representar lineas de
            %referencia
            for n = 1:1:length(Graficos)
                Grafico = Graficos(n);
                Grafico.Visible = 'On';
                Animacion = Animaciones(n);
                Animacion.Visible = 'On';
                
                if n ~= numerografico
                    
                    switch n
                        case 1
                            LineasGrafico(app,app.GraficoRpm,app.ValorLineaRPM,app.EstiloLineaRPM,"rpm")
                            
                        case 2
                            LineasGrafico(app,app.GraficoTemperatura,app.ValorLineaTemp,app.EstiloLineaTemp,"ºC")
                            
                        case 3
                            LineasGrafico(app,app.GraficoPresion,app.ValorLineaPresion,app.EstiloLineaPresion,"Bar")
                            
                        case 4
                            LineasGrafico(app,app.GraficoEmpuje,app.ValorLineaEmpuje,app.EstiloLineaEmpuje,"N")
                            
                        case 5
                            LineasGrafico(app,app.GraficoFlujoCombustible,app.ValorLineaCombustible,app.EstiloLineaCombustible,"ml/min") 
                            
                    end
                    
                end
                
            end
            
        end

        function AmpliaGraficosSecundarios(app,numerografico)
            %Ubicamos el grafico en el layout
            fila = numerografico*2-1;
            
            %Cambiamos la altura de las lineas del layout a 0            
            app.GridGraficosSecundarios.RowHeight = num2cell(zeros(1,length(app.GridGraficosSecundarios.RowHeight)));
            
            %Cambiamos los tamaños de las dos lineas que componen el
            %espacio del gráfica            
            app.GridGraficosSecundarios.RowHeight(fila) = {30};                 
            app.GridGraficosSecundarios.RowHeight(fila+1) ={'1x'};
        
            %Creamos unos vectores con las variables de los gráficos y las
            %animaciones
            Graficos = [app.GraficoValvGas,app.GraficoValvQuero,app.GraficoVoltajeBat,...
                app.GraficoVoltajeStarter,app.GraficoThrottle,app.GraficoPotBomba];
            Animaciones = [app.AnimacionValvGas,app.AnimacionValvQuero,app.AnimacionVoltaje,...
                app.AnimacionVoltajeStarter,app.AnimacionThrottle,app.AnimacionPotenciaBomba];
            
            %Recorremos los gráficos que no se van a ampliar para quitar la
            %visibilidad de los elementos y borrar las lineas          
            for n = 1:1:length(Graficos)
                if n ~= numerografico
                    Grafico = Graficos(n);
                    Grafico.Visible = 'Off';
                    Animacion = Animaciones(n);
                    Animacion.Visible = 'Off';
                    delete(Grafico.Children(1:end-1));
                end
                
            end
            
        end
        
        function ResetGraficosSecundarios(app,numerografico)
            %Restablecemos las alturas de todos las lineas del layout            
            for fila = 1:2:length(app.GridGraficosSecundarios.RowHeight)            
                app.GridGraficosSecundarios.RowHeight(fila) = {30};                 
                app.GridGraficosSecundarios.RowHeight(fila+1) ={'1x'};
                
            end
            
            %Creamos unos vectores con las variables de los gráficos y las
            %animaciones 
            Graficos = [app.GraficoValvGas,app.GraficoValvQuero,app.GraficoVoltajeBat,...
                app.GraficoVoltajeStarter,app.GraficoThrottle,app.GraficoPotBomba];
            Animaciones = [app.AnimacionValvGas,app.AnimacionValvQuero,app.AnimacionVoltaje,...
                app.AnimacionVoltajeStarter,app.AnimacionThrottle,app.AnimacionPotenciaBomba];

            %Reestablecemos gráficos y volvemos a representar lineas de
            %referencia            
            for n = 1:1:length(Graficos)
                Grafico = Graficos(n);
                Grafico.Visible = 'On';
                Animacion = Animaciones(n);
                Animacion.Visible = 'On';
                
                if n ~= numerografico
                    
                    switch n
                        case 3
                            LineasGrafico(app,app.GraficoVoltajeBat,app.ValorLineaVoltaje,app.EstiloLineaVoltaje,"V")  
                            
                        case 4
                            LineasGrafico(app,app.GraficoVoltajeStarter,app.ValorLineaStarter,app.EstiloLineaStarter,"V")
                            
                        case 5
                            LineasGrafico(app,app.GraficoThrottle,app.ValorLineaThrottle,app.EstiloLineaThrottle,"%")
                            
                        case 6
                            LineasGrafico(app,app.GraficoPotBomba,app.ValorLineaPotBomb,app.EstiloLineaPotBomb,"W") 
                            
                    end
                    
                end
                
            end
            
        end        
        
        
        function PreparaGraficos(app)
            try
                %Preparamos animaciones
                app.AnimacionRPM = animatedline(app.GraficoRpm,'Marker',".","MarkerSize",12);            
                app.AnimacionTemperatura = animatedline(app.GraficoTemperatura,'Marker',".","MarkerSize",12);
                app.AnimacionPresion = animatedline(app.GraficoPresion,'Marker',".","MarkerSize",12); 
                app.AnimacionEmpuje = animatedline(app.GraficoEmpuje,'Marker',".","MarkerSize",12);
                app.AnimacionFlujoCombustible = animatedline(app.GraficoFlujoCombustible,'Marker',".","MarkerSize",12);
                
                app.AnimacionValvGas = animatedline(app.GraficoValvGas,'Marker',".","MarkerSize",12); 
                app.AnimacionValvQuero = animatedline(app.GraficoValvQuero,'Marker',".","MarkerSize",12); 
                app.AnimacionVoltaje = animatedline(app.GraficoVoltajeBat,'Marker',".","MarkerSize",12); 
                app.AnimacionVoltajeStarter = animatedline(app.GraficoVoltajeStarter,'Marker',".","MarkerSize",12); 
                app.AnimacionThrottle = animatedline(app.GraficoThrottle,'Marker',".","MarkerSize",12); 
                app.AnimacionPotenciaBomba = animatedline(app.GraficoPotBomba,'Marker',".","MarkerSize",12); 
                
                %Preparamos las mallas
                app.GridGraficosPrimarios.ColumnWidth = {'1x',30};
                app.GridGraficosPrimarios.RowHeight = {30,'1x',30,'1x',30,'1x',30,'1x',30,'1x'};
                
                app.GridGraficosSecundarios.ColumnWidth = {'1x',30};
                app.GridGraficosSecundarios.RowHeight = {30,'1x',30,'1x',30,'1x',30,'1x',30,'1x',30,'1x'};
                
                %Configuracion grafico rpm                
                app.GraficoRpm.XLim = app.RangoX;
                app.GraficoRpm.YLimMode = 'Manual';
                app.GraficoRpm.YLim = app.RangoYRPM;
                app.GraficoRpm.YAxis.Exponent = 0;
                LineasGrafico(app,app.GraficoRpm,app.ValorLineaRPM,app.EstiloLineaRPM,"rpm")
                
                %Configuracion grafico temperatura
                app.GraficoTemperatura.XLim = app.RangoX;
                app.GraficoTemperatura.YLimMode = 'Manual';
                app.GraficoTemperatura.YLim = app.RangoYTemp;
                LineasGrafico(app,app.GraficoTemperatura,app.ValorLineaTemp,app.EstiloLineaTemp,"ºC")
                
                %Configuracion grafico presion
                app.GraficoPresion.XLim = app.RangoX;
                app.GraficoPresion.YLimMode = 'Manual';  
                app.GraficoPresion.YLim = app.RangoYPresion;
                LineasGrafico(app,app.GraficoPresion,app.ValorLineaPresion,app.EstiloLineaPresion,"Bar")
                
                %Configuracion grafico empuje   
                app.GraficoEmpuje.XLim = app.RangoX;
                app.GraficoEmpuje.YLimMode = 'Manual';
                app.GraficoEmpuje.YLim = app.RangoYEmpuje;
                LineasGrafico(app,app.GraficoEmpuje,app.ValorLineaEmpuje,app.EstiloLineaEmpuje,"N")
                
                %Configuracion grafico flujo combustible          
                app.GraficoFlujoCombustible.XLim = app.RangoX;
                app.GraficoFlujoCombustible.YLimMode = 'Manual';
                app.GraficoFlujoCombustible.YLim = app.RangoYCombustible;
                LineasGrafico(app,app.GraficoFlujoCombustible,app.ValorLineaCombustible,app.EstiloLineaCombustible,"ml/min")
                
                %Configuracion grafico valvula gas          
                app.GraficoValvGas.XLim = app.RangoX;
                app.GraficoValvGas.YLimMode = 'Manual';
                app.GraficoValvGas.YLim = app.RangoYValvGas;
                            
                %Configuracion grafico valvula queroseno      
                app.GraficoValvQuero.XLim = app.RangoX;
                app.GraficoValvQuero.YLimMode = 'Manual';
                app.GraficoValvQuero.YLim = app.RangoYValvQuero;
                
                %Configuracion grafico voltaje bateria          
                app.GraficoVoltajeBat.XLim = app.RangoX;
                app.GraficoVoltajeBat.YLimMode = 'Manual';
                app.GraficoVoltajeBat.YLim = app.RangoYVoltaje;
                LineasGrafico(app,app.GraficoVoltajeBat,app.ValorLineaVoltaje,app.EstiloLineaVoltaje,"V")
                            
                %Configuracion grafico voltaje starter      
                app.GraficoVoltajeStarter.XLim = app.RangoX;
                app.GraficoVoltajeStarter.YLimMode = 'Manual';
                app.GraficoVoltajeStarter.YLim = app.RangoYStarter;
                LineasGrafico(app,app.GraficoVoltajeStarter,app.ValorLineaStarter,app.EstiloLineaStarter,"V")
                
                %Configuracion grafico throttle         
                app.GraficoThrottle.XLim = app.RangoX;
                app.GraficoThrottle.YLimMode = 'Manual';
                app.GraficoThrottle.YLim = app.RangoYThrottle;
                LineasGrafico(app,app.GraficoThrottle,app.ValorLineaThrottle,app.EstiloLineaThrottle,"%")
                            
                %Configuracion grafico pot bomba        
                app.GraficoPotBomba.XLim = app.RangoX;
                app.GraficoPotBomba.YLimMode = 'Manual';
                app.GraficoPotBomba.YLim = app.RangoYPotBomb;
                LineasGrafico(app,app.GraficoPotBomba,app.ValorLineaPotBomb,app.EstiloLineaPotBomb,"w")
          
                %Preparamos botones
                app.BotonAmplia1.Icon = "Lupa.png";
                app.BotonAmplia2.Icon = "Lupa.png";
                app.BotonAmplia3.Icon = "Lupa.png";
                app.BotonAmplia4.Icon = "Lupa.png";
                app.BotonAmplia5.Icon = "Lupa.png";
                
                app.BotonAmplia6.Icon = "Lupa.png";
                app.BotonAmplia7.Icon = "Lupa.png";
                app.BotonAmplia8.Icon = "Lupa.png";
                app.BotonAmplia9.Icon = "Lupa.png";
                app.BotonAmplia10.Icon = "Lupa.png";
                app.BotonAmplia11.Icon = "Lupa.png";
                
                %Ajustamos las etiquetas del eje Y de los gráficos (estética)
                Espaciado = {"          0"};
                app.GraficoRpm.YTick(1) = 0;
                app.GraficoRpm.YTickLabel(1) = Espaciado;
                app.GraficoTemperatura.YTick(1) = 0;
                app.GraficoTemperatura.YTickLabel(1) = Espaciado;
                app.GraficoPresion.YTick(1) = 0;
                app.GraficoPresion.YTickLabel(1) = Espaciado;
                app.GraficoEmpuje.YTick(1) = 0;
                app.GraficoEmpuje.YTickLabel(1) = Espaciado;
                app.GraficoFlujoCombustible.YTick(1) = 0;
                app.GraficoFlujoCombustible.YTickLabel(1) = Espaciado;
                
                app.GraficoValvGas.YTick(1) = 0;
                app.GraficoValvGas.YTickLabel(1) = {"               0"};
                app.GraficoValvQuero.YTick(1) = 0;
                app.GraficoValvQuero.YTickLabel(1) = {"               0"};
                app.GraficoVoltajeBat.YTick(1) = 0;
                app.GraficoVoltajeBat.YTickLabel(1) = Espaciado;
                app.GraficoVoltajeStarter.YTick(1) = 0;
                app.GraficoVoltajeStarter.YTickLabel(1) = Espaciado;
                app.GraficoThrottle.YTick(1) = 0;
                app.GraficoThrottle.YTickLabel(1) = Espaciado;
                app.GraficoPotBomba.YTick(1) = 0;
                app.GraficoPotBomba.YTickLabel(1) = Espaciado;
            catch
            end
            
        end
        
        function LineasGrafico(app,grafico,valoreslineas,estiloslineas,unidades)
            %Comprobamos si hay valores de configuración
            if ~isnan(valoreslineas(1))
                
                %Creamos las lineas de referencia 
                for n = 1:1:length(valoreslineas)
                    linea = yline(grafico,valoreslineas(n),estiloslineas(n));
                    linea.Label = sprintf(num2str(valoreslineas(n))+unidades);
                    linea.LabelVerticalAlignment = 'Middle'; 
                    
                end
                
            end
            
        end
        
        function ActualizaEjes(app)
            %Calculamos los valores maximos y minimos del eje X de los
            %graficos
            LimitesX = [app.TiempoTranscurrido+app.RangoX(1) app.TiempoTranscurrido+app.RangoX(2)];
            
            %Actualizamos ejes de los gráficos
            app.GraficoRpm.XLim = LimitesX; 
            app.GraficoTemperatura.XLim = LimitesX; 
            app.GraficoPresion.XLim = LimitesX; 
            app.GraficoEmpuje.XLim = LimitesX; 
            app.GraficoFlujoCombustible.XLim = LimitesX;
            
            app.GraficoValvGas.XLim = LimitesX; 
            app.GraficoValvQuero.XLim = LimitesX; 
            app.GraficoVoltajeBat.XLim = LimitesX; 
            app.GraficoVoltajeStarter.XLim = LimitesX; 
            app.GraficoThrottle.XLim = LimitesX; 
            app.GraficoPotBomba.XLim = LimitesX; 
    
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Cargamos la configuradción de los gráficos
            CargaConfiguracion(app);
            
            %Preparamos los gfráficos con la configuración cargada
            %anteriormente
            PreparaGraficos(app);
            
        end

        % Button pushed function: Conectar
        function ConectarButtonPushed(app, event)
            %Creamos los objetos de comunicacion serie del Arduino y DSCUSB
            Conecta(app);
            
        end

        % Button pushed function: BotonMenuAbajo
        function BotonMenuAbajoButtonPushed(app, event)
            %Probamos a mandar orden a Arduino
            try
                writeline(app.ComArduino,'F');
            
            catch
                %Avisamos de error de conexión con arduino                
                uialert(app.UIFigure,"Error de conexión con Arduino.","Error","Icon","error");
                
            end
            
        end

        % Button pushed function: BotonMenuArriba
        function BotonMenuArribaButtonPushed(app, event)
            %Probamos a comunicarnos con Arduino
            try
                writeline(app.ComArduino,'E');
            
            catch
                %Avisamos de error de conexión con arduino                                
                uialert(app.UIFigure,"Error de conexión con Arduino.","Error","Icon","error");                
            
            end
            
        end

        % Button pushed function: BotonDatosAbajo
        function BotonDatosAbajoButtonPushed(app, event)
            %Probamos a mandar orden a Arduino
            try
                writeline(app.ComArduino,'D');
            
            catch
                %Avisamos de error de conexión con arduino                  
                uialert(app.UIFigure,"Error de conexión con Arduino.","Error","Icon","error");                
            
            end
            
        end

        % Button pushed function: BotonDatosArriba
        function BotonDatosArribaButtonPushed(app, event)
            %Probamos a mandar orden a Arduino
            try
                writeline(app.ComArduino,'C');
            
            catch
                %Avisamos de error de conexión con arduino                  
                uialert(app.UIFigure,"Error de conexión con Arduino.","Error","Icon","error");                
            
            end
            
        end

        % Button pushed function: BotonEnviarControlManual
        function BotonEnviarControlManualButtonPushed(app, event)
            %Probamos a enviar el contenido del campo esitable a Arduino
            try
                writeline(app.ComArduino,app.CampoControlManual.Value);
                
            catch
                %Avisamos de error de conexión con arduino                  
                uialert(app.UIFigure,"Error de conexión con Arduino.","Error","Icon","error");
                
            end            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Pedimos conformación de cierre si hay datos en RAM
            if(~isempty(app.Tiempo))
                respuesta = uiconfirm(app.UIFigure,'¿Desea guardar los datos antes de salir?','Alerta',"Options",{'Si','No','Cancelar'},'DefaultOption',3,'CancelOption',3);
                switch  respuesta
                    
                    %Si seleccionamos "Si" guardamos los datos antes de
                    %cerrar la aplicación                                           
                    case 'Si'
                        GuardarDatos(app);
                        delete(app);
                    
                    %Si seleccionamos "No" cerramos la aplicación sin
                    %guardar
                    case 'No'
                        delete(app);
                        
                    %Si seleccionamos "Cancelar" no hacemos nada                  
                    case 'Cancelar'
                        
                end

            %Si no hay datos en RAM cerramos la aplicación    
            else
                delete(app);

            end
           
        end

        % Value changed function: BotonAmplia1
        function BotonAmplia1ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos
            value = app.BotonAmplia1.Value;
            numerografico = 1;
            
            switch value
                case 1
                    app.BotonAmplia1.Icon = 'LupaOscura.png';
                    AmpliaGraficosPrimarios(app,numerografico);
                    
                case 0
                    app.BotonAmplia1.Icon = 'Lupa.png';
                    ResetGraficosPrimarios(app,numerografico);
                    
            end    
            
        end

        % Value changed function: BotonAmplia2
        function BotonAmplia2ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos            
            value = app.BotonAmplia2.Value;
            numerografico = 2;
            
            switch value
                case 1
                    app.BotonAmplia2.Icon = 'LupaOscura.png';
                    AmpliaGraficosPrimarios(app,numerografico);
            
                case 0
                    app.BotonAmplia2.Icon = 'Lupa.png';
                    ResetGraficosPrimarios(app,numerografico);
            
            end               
            
        end

        % Value changed function: BotonAmplia3
        function BotonAmplia3ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos
            value = app.BotonAmplia3.Value;
            numerografico = 3;
            
            switch value
                case 1
                    app.BotonAmplia3.Icon = 'LupaOscura.png';
                    AmpliaGraficosPrimarios(app,numerografico);
            
                case 0
                    app.BotonAmplia3.Icon = 'Lupa.png';
                    ResetGraficosPrimarios(app,numerografico);
            
            end
            
        end

        % Value changed function: BotonAmplia4
        function BotonAmplia4ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos            
            value = app.BotonAmplia4.Value;
            numerografico = 4;
            
            switch value
                case 1
                    app.BotonAmplia4.Icon = 'LupaOscura.png';
                    AmpliaGraficosPrimarios(app,numerografico);
            
                case 0
                    app.BotonAmplia4.Icon = 'Lupa.png';
                    ResetGraficosPrimarios(app,numerografico);
            
            end
            
        end

        % Value changed function: BotonAmplia5
        function BotonAmplia5ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos
            value = app.BotonAmplia5.Value;
            numerografico = 5;
            
            switch value
                case 1
                    app.BotonAmplia5.Icon = 'LupaOscura.png';
                    AmpliaGraficosPrimarios(app,numerografico);
            
                case 0
                    app.BotonAmplia5.Icon = 'Lupa.png';
                    ResetGraficosPrimarios(app,numerografico);
            
            end
            
        end

        % Button pushed function: ArranqueButton
        function ArranqueButtonPushed(app, event)
            %Pedimos confirmación de arranque
            respuesta = uiconfirm(app.UIFigure,'¿Esta seguro de que quiere arrancar el motor?','Alerta','Options',{'Si','No'},"DefaultOption",2,"CancelOption",2);
            
            %Comprobamos si el motor esta arrancado
            if app.EstaArrancado == 1
                %Avisamos de que el motor no se puede arrancar porque esta
                %arrancado
                uialert(app.UIFigure,"El motor se encuentra arrancado.","Error","Icon","error");
                
            else
                switch respuesta
                    case 'Si'
                        app.EstaArrancado = 1;
                        app.LuzArrancado.Color = [0,1,0];
                        
                        %Intentamos realizar la secuencia de arranque
                        try
                            %Ponemos el acelerador en idle y representamos los
                            %datos
                            writeline(app.ComArduino,"25");
                            app.TextoComandos.Text = "Acelerador 25%";
                            pause(2);
                            LeeDatos(app);                        
                            RepresentaDatos(app);
                            
                            %Ponemos el acelerador al 100% y representamos los
                            %datos
                            writeline(app.ComArduino,"100");
                            app.TextoComandos.Text = "Acelerador 100%";
                            pause(2);
                            LeeDatos(app);                        
                            RepresentaDatos(app);
    
                            %Ponemos el acelerador en idle y representamos los
                            %datos
                            writeline(app.ComArduino,"25");
                            app.TextoComandos.Text = "Acelerador 25%";
                            pause(2);
                            LeeDatos(app);
                            RepresentaDatos(app);
                            
                        catch
                            %Avisamos de error de conexión con arduino
                            uialert(app.UIFigure,"Error de conexión con Arduino.","Error","Icon","error");
                            
                            %Reseteamos variables a antes de arrancar
                            app.EstaArrancado = 0;
                            app.LuzArrancado.Color = [1,0,0];
                            writeline(app.ComArduino,"0");
                            app.TextoComandos.Text = "Acelerador 0%";
                            
                        
                        end
                end
            
            end
            
        end

        % Button pushed function: ParadaButton
        function ParadaButtonPushed(app, event)
            %Confirmamos que queremos parar el motor
            respuesta = uiconfirm(app.UIFigure,'¿Esta seguro de que quiere parar el motor?','Alerta','Options',{'Si','No'},"DefaultOption",2,"CancelOption",2);
            
            switch respuesta
                case 'Si'
                    try
                        %Ponemos el acelerador a 0 he indicamos que estamos
                        %parando
                        writeline(app.ComArduino,"0");app.TextoComandos.Text = "Acelerador 0%";
                        app.EstaParando = 1;
                    
                    catch
                        %Avisamos de error de conexión con arduino                        
                        uialert(app.UIFigure,"Error de conexión con Arduino.","Error","Icon","error"); 
                    
                    end
                    
            end     
            
        end

        % Menu selected function: GuardardatosMenu
        function GuardardatosMenuSelected(app, event)
            %Guarda datos
            GuardarDatos(app);
            
        end

        % Menu selected function: NuevoensayoMenu
        function NuevoensayoMenuSelected(app, event)
            %En función del estado del motor podremos resetear todo y abrir
            %un nuevo ensayo o no. Si el motor esta arrancado no nos dejará
            %realizarlo
            switch app.EstaArrancado
                case 1
                    uialert(app.UIFigure,"No se puede realizar un nuevo ensayo mientras el motor esta en funcionamiento.","Error","Icon","error");
                    
                case 0            
                    BorrarDatos(app);
                    app.NuevoEnsayo = 0;
                    CargaConfiguracion(app);
                    PreparaGraficos(app);
                    TaraDSCUSB(app);
                    uialert(app.UIFigure,"Graficos y variables reiniciados con éxito.","Información","Icon","info");
                    
            end

        end

        % Menu selected function: AcercadeMenu
        function AcercadeMenuSelected(app, event)
            %Abrimos ventana AcercaDE
            run("AcercaDe.mlapp");
            
        end

        % Menu selected function: ConfiguracinMenu
        function ConfiguracinMenuSelected(app, event)
            %Abrimos ventana de configuración
            run("Configuracion.mlapp");
            
        end

        % Value changed function: BotonAmplia6
        function BotonAmplia6ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos
            value = app.BotonAmplia6.Value;
            numerografico = 1;
            
            switch value
                case 1
                    app.BotonAmplia1.Icon = 'LupaOscura.png';
                    AmpliaGraficosSecundarios(app,numerografico);
            
                case 0
                    app.BotonAmplia1.Icon = 'Lupa.png';
                    ResetGraficosSecundarios(app,numerografico);
            
            end
            
        end

        % Value changed function: BotonAmplia7
        function BotonAmplia7ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos            
            value = app.BotonAmplia7.Value;
            numerografico = 2;
            
            switch value
                case 1
                    app.BotonAmplia1.Icon = 'LupaOscura.png';
                    AmpliaGraficosSecundarios(app,numerografico);
            
                case 0
                    app.BotonAmplia1.Icon = 'Lupa.png';
                    ResetGraficosSecundarios(app,numerografico);
            
            end  
            
        end

        % Value changed function: BotonAmplia8
        function BotonAmplia8ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos
            value = app.BotonAmplia8.Value;
            numerografico = 3;
            
            switch value
                case 1
                    app.BotonAmplia1.Icon = 'LupaOscura.png';
                    AmpliaGraficosSecundarios(app,numerografico);
            
                case 0
                    app.BotonAmplia1.Icon = 'Lupa.png';
                    ResetGraficosSecundarios(app,numerografico);
            
            end              
            
        end

        % Value changed function: BotonAmplia9
        function BotonAmplia9ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos            
            value = app.BotonAmplia9.Value;
            numerografico = 4;
            
            switch value
                case 1
                    app.BotonAmplia1.Icon = 'LupaOscura.png';
                    AmpliaGraficosSecundarios(app,numerografico);
            
                case 0
                    app.BotonAmplia1.Icon = 'Lupa.png';
                    ResetGraficosSecundarios(app,numerografico);
            
            end              
            
        end

        % Value changed function: BotonAmplia10
        function BotonAmplia10ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos            
            value = app.BotonAmplia10.Value;
            numerografico = 5;
            
            switch value
                case 1
                    app.BotonAmplia1.Icon = 'LupaOscura.png';
                    AmpliaGraficosSecundarios(app,numerografico);
            
                case 0
                    app.BotonAmplia1.Icon = 'Lupa.png';
                    ResetGraficosSecundarios(app,numerografico);
            
            end
            
        end

        % Value changed function: BotonAmplia11
        function BotonAmplia11ValueChanged(app, event)
            %Dependiendo de si el botón de ampliar "x" esta en una posición
            %o en otra ampliamos o reseteamos los gráficos
            value = app.BotonAmplia11.Value;
            numerografico = 6;
            
            switch value
                case 1
                    app.BotonAmplia1.Icon = 'LupaOscura.png';
                    AmpliaGraficosSecundarios(app,numerografico);
            
                case 0
                    app.BotonAmplia1.Icon = 'Lupa.png';
                    ResetGraficosSecundarios(app,numerografico);
            
            end              
            
        end

        % Value changed function: ApagadodeemergenciaButton
        function ApagadodeemergenciaButtonValueChanged(app, event)
            %Dependiendo del valor del botón apagado de emergencia
            %intentaremos mandar a Arduino el comando para que realice un
            %apagado de emergencia (Corte suministro combustible)
            value = app.ApagadodeemergenciaButton.Value;
            
            switch  value
                case 1
                    try
                        writeline(app.ComArduino,'X');
                        
                        %Luz apagado de emergencia en verde
                        app.Lamp.Color = [0,1,0];
                        app.EstaParando = 1;
                        app.TextoComandos.Text="Apagado de emergencia ON";
                    
                    catch
                        %Avisamos de error de conexión con arduino                        
                        uialert(app.UIFigure,"Error de conexión con Arduino.","Error","Icon","error"); 
                        app.ApagadodeemergenciaButton.Value = 0;
                    
                    end
                    
                case 0
                    try
                        writeline(app.ComArduino,"Z");
                        
                        %Luz apagado de emergencia en Rojo
                        app.Lamp.Color = [1,0,0];
                        app.TextoComandos.Text="Apagado de emergencia OFF";
                        
                    catch
                        %Avisamos de error de conexión con arduino                        
                        uialert(app.UIFigure,"Error de conexión con Arduino.","Error","Icon","error");
                        app.ApagadodeemergenciaButton.Value = 1;
                        
                    end
                    
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MenMenu = uimenu(app.UIFigure);
            app.MenMenu.Text = 'Menú';
            app.NuevoensayoMenu = uimenu(app.MenMenu);
            app.NuevoensayoMenu.MenuSelectedFcn = createCallbackFcn(app, @NuevoensayoMenuSelected, true);
            app.NuevoensayoMenu.Text = 'Nuevo ensayo';
            app.GuardardatosMenu = uimenu(app.MenMenu);
            app.GuardardatosMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardardatosMenuSelected, true);
            app.GuardardatosMenu.Text = 'Guardar datos';
            app.ConfiguracinMenu = uimenu(app.UIFigure);
            app.ConfiguracinMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfiguracinMenuSelected, true);
            app.ConfiguracinMenu.Text = 'Configuración';
            app.AcercadeMenu = uimenu(app.UIFigure);
            app.AcercadeMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadeMenuSelected, true);
            app.AcercadeMenu.Text = 'Acerca de';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.ComunicacionPanel = uipanel(app.GridLayout2);
            app.ComunicacionPanel.Title = 'Comunicación y control';
            app.GridLayout = uigridlayout(app.ComunicacionPanel);
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.PantallaHDT = uilabel(app.GridLayout3);
            app.PantallaHDT.Text = 'HDT1';
            app.PantallaHDT2 = uilabel(app.GridLayout3);
            app.PantallaHDT2.Text = 'HDT2';
            app.Conectar = uibutton(app.GridLayout, 'push');
            app.Conectar.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.Conectar.Text = 'Conectar';
            app.EstadoConexion = uilabel(app.GridLayout);
            app.EstadoConexion.Text = 'Estado Conexion';
            app.BotonMenuAbajo = uibutton(app.GridLayout, 'push');
            app.BotonMenuAbajo.ButtonPushedFcn = createCallbackFcn(app, @BotonMenuAbajoButtonPushed, true);
            app.BotonMenuAbajo.Text = '↓';
            app.BotonMenuArriba = uibutton(app.GridLayout, 'push');
            app.BotonMenuArriba.ButtonPushedFcn = createCallbackFcn(app, @BotonMenuArribaButtonPushed, true);
            app.BotonMenuArriba.Text = '↑';
            app.BotonDatosAbajo = uibutton(app.GridLayout, 'push');
            app.BotonDatosAbajo.ButtonPushedFcn = createCallbackFcn(app, @BotonDatosAbajoButtonPushed, true);
            app.BotonDatosAbajo.Text = '-';
            app.BotonDatosArriba = uibutton(app.GridLayout, 'push');
            app.BotonDatosArriba.ButtonPushedFcn = createCallbackFcn(app, @BotonDatosArribaButtonPushed, true);
            app.BotonDatosArriba.Text = '+';
            app.CampoControlManual = uieditfield(app.GridLayout, 'text');
            app.CampoControlManual.Placeholder = 'Escriba el comando aquí';
            app.BotonEnviarControlManual = uibutton(app.GridLayout, 'push');
            app.BotonEnviarControlManual.ButtonPushedFcn = createCallbackFcn(app, @BotonEnviarControlManualButtonPushed, true);
            app.BotonEnviarControlManual.Text = 'Enviar';
            app.TextoComandos = uilabel(app.GridLayout);
            app.TextoComandos.Text = {'Comandos'; ''};
            app.ApagadodeemergenciaButton = uibutton(app.GridLayout, 'state');
            app.ApagadodeemergenciaButton.ValueChangedFcn = createCallbackFcn(app, @ApagadodeemergenciaButtonValueChanged, true);
            app.ApagadodeemergenciaButton.Text = 'Apagado de emergencia';
            app.Lamp = uilamp(app.GridLayout);
            app.ArranqueButton = uibutton(app.GridLayout, 'push');
            app.ArranqueButton.ButtonPushedFcn = createCallbackFcn(app, @ArranqueButtonPushed, true);
            app.ArranqueButton.Text = 'Arranque';
            app.ParadaButton = uibutton(app.GridLayout, 'push');
            app.ParadaButton.ButtonPushedFcn = createCallbackFcn(app, @ParadaButtonPushed, true);
            app.ParadaButton.Text = 'Parada';
            app.LuzArrancado = uilamp(app.GridLayout);
            app.ArrancadoParadoLabel = uilabel(app.GridLayout);
            app.ArrancadoParadoLabel.Text = 'Arrancado/Parado:';
            app.TelemetraPanel = uipanel(app.GridLayout2);
            app.TelemetraPanel.Title = 'Telemetría';
            app.GridMetricas = uigridlayout(app.TelemetraPanel);
            app.MetricasSecundarias = uilabel(app.GridMetricas);
            app.MetricasSecundarias.Text = 'Telemetría secundaria';
            app.TextoTiempoMuestreo = uilabel(app.GridMetricas);
            app.TextoTiempoMuestreo.Text = 'Tiempo de muestreo';
            app.TextoTiempoTranscurrido = uilabel(app.GridMetricas);
            app.TextoTiempoTranscurrido.Text = 'Tiempo total';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.GraficosPrimarios = uitab(app.TabGroup);
            app.GraficosPrimarios.Title = 'Gráficos primarios';
            app.GridGraficosPrimarios = uigridlayout(app.GraficosPrimarios);
            app.GraficoTemperatura = uiaxes(app.GridGraficosPrimarios);
            title(app.GraficoTemperatura, 'Temperatura')
            ylabel(app.GraficoTemperatura, 'ºC')
            app.GraficoTemperatura.XLim = [0 1];
            app.GraficoTemperatura.LineStyleOrder = {'-'};
            app.GraficoPresion = uiaxes(app.GridGraficosPrimarios);
            title(app.GraficoPresion, 'Presión')
            ylabel(app.GraficoPresion, 'Bar')
            app.GraficoPresion.LineStyleOrder = {'-'};
            app.GraficoEmpuje = uiaxes(app.GridGraficosPrimarios);
            title(app.GraficoEmpuje, 'Empuje')
            ylabel(app.GraficoEmpuje, 'kgf')
            app.GraficoEmpuje.LineStyleOrder = {'-'};
            app.GraficoRpm = uiaxes(app.GridGraficosPrimarios);
            title(app.GraficoRpm, 'RPM')
            ylabel(app.GraficoRpm, 'RPM')
            app.GraficoFlujoCombustible = uiaxes(app.GridGraficosPrimarios);
            title(app.GraficoFlujoCombustible, 'Flujo Combustible')
            ylabel(app.GraficoFlujoCombustible, 'ml/min')
            app.GraficoFlujoCombustible.LineStyleOrder = {'-'};
            app.BotonAmplia1 = uibutton(app.GridGraficosPrimarios, 'state');
            app.BotonAmplia1.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia1ValueChanged, true);
            app.BotonAmplia1.Text = '';
            app.BotonAmplia2 = uibutton(app.GridGraficosPrimarios, 'state');
            app.BotonAmplia2.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia2ValueChanged, true);
            app.BotonAmplia2.Text = '';
            app.BotonAmplia3 = uibutton(app.GridGraficosPrimarios, 'state');
            app.BotonAmplia3.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia3ValueChanged, true);
            app.BotonAmplia3.Text = '';
            app.BotonAmplia4 = uibutton(app.GridGraficosPrimarios, 'state');
            app.BotonAmplia4.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia4ValueChanged, true);
            app.BotonAmplia4.Text = '';
            app.BotonAmplia5 = uibutton(app.GridGraficosPrimarios, 'state');
            app.BotonAmplia5.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia5ValueChanged, true);
            app.BotonAmplia5.Text = '';
            app.GrficossecundariosTab = uitab(app.TabGroup);
            app.GrficossecundariosTab.Title = 'Gráficos secundarios';
            app.GridGraficosSecundarios = uigridlayout(app.GrficossecundariosTab);
            app.GraficoValvQuero = uiaxes(app.GridGraficosSecundarios);
            title(app.GraficoValvQuero, 'Válvula Queroseno')
            zlabel(app.GraficoValvQuero, 'Z')
            app.GraficoVoltajeBat = uiaxes(app.GridGraficosSecundarios);
            title(app.GraficoVoltajeBat, 'Voltaje Batería')
            ylabel(app.GraficoVoltajeBat, 'V')
            zlabel(app.GraficoVoltajeBat, 'Z')
            app.GraficoVoltajeStarter = uiaxes(app.GridGraficosSecundarios);
            title(app.GraficoVoltajeStarter, 'VoltajeStarter')
            ylabel(app.GraficoVoltajeStarter, 'V')
            zlabel(app.GraficoVoltajeStarter, 'Z')
            app.GraficoThrottle = uiaxes(app.GridGraficosSecundarios);
            title(app.GraficoThrottle, 'Throttle')
            ylabel(app.GraficoThrottle, '%')
            zlabel(app.GraficoThrottle, 'Z')
            app.GraficoPotBomba = uiaxes(app.GridGraficosSecundarios);
            title(app.GraficoPotBomba, 'Potencia Bomba')
            ylabel(app.GraficoPotBomba, 'W')
            zlabel(app.GraficoPotBomba, 'Z')
            app.GraficoValvGas = uiaxes(app.GridGraficosSecundarios);
            title(app.GraficoValvGas, 'Válvula Gas')
            zlabel(app.GraficoValvGas, 'Z')
            app.BotonAmplia6 = uibutton(app.GridGraficosSecundarios, 'state');
            app.BotonAmplia6.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia6ValueChanged, true);
            app.BotonAmplia6.Text = '';
            app.BotonAmplia7 = uibutton(app.GridGraficosSecundarios, 'state');
            app.BotonAmplia7.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia7ValueChanged, true);
            app.BotonAmplia7.Text = '';
            app.BotonAmplia8 = uibutton(app.GridGraficosSecundarios, 'state');
            app.BotonAmplia8.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia8ValueChanged, true);
            app.BotonAmplia8.Text = '';
            app.BotonAmplia9 = uibutton(app.GridGraficosSecundarios, 'state');
            app.BotonAmplia9.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia9ValueChanged, true);
            app.BotonAmplia9.Text = '';
            app.BotonAmplia10 = uibutton(app.GridGraficosSecundarios, 'state');
            app.BotonAmplia10.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia10ValueChanged, true);
            app.BotonAmplia10.Text = '';
            app.BotonAmplia11 = uibutton(app.GridGraficosSecundarios, 'state');
            app.BotonAmplia11.ValueChangedFcn = createCallbackFcn(app, @BotonAmplia11ValueChanged, true);
            app.BotonAmplia11.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlTurbina

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end