classdef Configuracion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GrficoEmpujePanel              matlab.ui.container.Panel
        GridLayout3_9                  matlab.ui.container.GridLayout
        I3                             matlab.ui.control.EditField
        EstilolineasauxiliaresLabel_9  matlab.ui.control.Label
        I2                             matlab.ui.control.EditField
        LineasauxiliaresLabel_9        matlab.ui.control.Label
        I1                             matlab.ui.control.EditField
        RangoejeYLabel_9               matlab.ui.control.Label
        AlintroducirmsdeunvalorhandesepararseporespaciosEj20120300Label  matlab.ui.control.Label
        ValorespredeterminadosButton   matlab.ui.control.Button
        GrficoVoltajeMotorArranquePanel  matlab.ui.container.Panel
        GridLayout3_8                  matlab.ui.container.GridLayout
        H3                             matlab.ui.control.EditField
        EstilolineasauxiliaresLabel_8  matlab.ui.control.Label
        H2                             matlab.ui.control.EditField
        LineasauxiliaresLabel_8        matlab.ui.control.Label
        H1                             matlab.ui.control.EditField
        RangoejeYLabel_8               matlab.ui.control.Label
        GrficoCaudalCombustibleLquidoPanel  matlab.ui.container.Panel
        GridLayout3_7                  matlab.ui.container.GridLayout
        G3                             matlab.ui.control.EditField
        EstilolineasauxiliaresLabel_7  matlab.ui.control.Label
        G2                             matlab.ui.control.EditField
        LineasauxiliaresLabel_7        matlab.ui.control.Label
        G1                             matlab.ui.control.EditField
        RangoejeYLabel_7               matlab.ui.control.Label
        GrficoPresinPanel              matlab.ui.container.Panel
        GridLayout3_6                  matlab.ui.container.GridLayout
        F3                             matlab.ui.control.EditField
        EstilolineasauxiliaresLabel_6  matlab.ui.control.Label
        F2                             matlab.ui.control.EditField
        LineasauxiliaresLabel_6        matlab.ui.control.Label
        F1                             matlab.ui.control.EditField
        RangoejeYLabel_6               matlab.ui.control.Label
        CerrarButton                   matlab.ui.control.Button
        GuardarButton                  matlab.ui.control.Button
        GrficoVoltajeBateraPanel       matlab.ui.container.Panel
        GridLayout3_5                  matlab.ui.container.GridLayout
        E3                             matlab.ui.control.EditField
        EstilolineasauxiliaresLabel_5  matlab.ui.control.Label
        E2                             matlab.ui.control.EditField
        LineasauxiliaresLabel_5        matlab.ui.control.Label
        E1                             matlab.ui.control.EditField
        RangoejeYLabel_5               matlab.ui.control.Label
        GrficoAceleradorPanel          matlab.ui.container.Panel
        GridLayout3_4                  matlab.ui.container.GridLayout
        D3                             matlab.ui.control.EditField
        EstilolineasauxiliaresLabel_4  matlab.ui.control.Label
        D2                             matlab.ui.control.EditField
        LineasauxiliaresLabel_4        matlab.ui.control.Label
        D1                             matlab.ui.control.EditField
        RangoejeYLabel_4               matlab.ui.control.Label
        GrficoPotenciaBombaPanel       matlab.ui.container.Panel
        GridLayout3_3                  matlab.ui.container.GridLayout
        C3                             matlab.ui.control.EditField
        EstilolineasauxiliaresLabel_3  matlab.ui.control.Label
        C2                             matlab.ui.control.EditField
        LineasauxiliaresLabel_3        matlab.ui.control.Label
        C1                             matlab.ui.control.EditField
        RangoejeYLabel_3               matlab.ui.control.Label
        GrficoTemperaturaPanel         matlab.ui.container.Panel
        GridLayout3_2                  matlab.ui.container.GridLayout
        B2                             matlab.ui.control.EditField
        B3                             matlab.ui.control.EditField
        EstilolineasauxiliaresLabel_2  matlab.ui.control.Label
        LineasauxiliaresLabel_2        matlab.ui.control.Label
        B1                             matlab.ui.control.EditField
        RangoejeYLabel_2               matlab.ui.control.Label
        GrficoRgimenGiroPanel          matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        A3                             matlab.ui.control.EditField
        EstilolineasauxiliaresLabel    matlab.ui.control.Label
        A2                             matlab.ui.control.EditField
        LineasauxiliaresLabel          matlab.ui.control.Label
        A1                             matlab.ui.control.EditField
        RangoejeYLabel                 matlab.ui.control.Label
        GeneralPanel                   matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GENERAL                        matlab.ui.control.EditField
        RangodetiempoLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        %Directorios
        DirectorioIconos = addpath(genpath('Icons'));
        
        %Valores graficos globales
        RangoX;

        %Valores grafico rpm
        RangoYRPM;            
        ValorLineaRPM;
        EstiloLineaRPM;
        
        %Valores grafico temperatura
        RangoYTemp;
        ValorLineaTemp;
        EstiloLineaTemp;
        
        %Valores grafico potencia bomba
        RangoYPotBomb;
        ValorLineaPotBomb;
        EstiloLineaPotBomb;            
        
        %Valores grafico throttle
        RangoYThrottle;
        ValorLineaThrottle;
        EstiloLineaThrottle; 
        
        %Valores grafico voltaje
        RangoYVoltaje;
        ValorLineaVoltaje;
        EstiloLineaVoltaje;
        
        %Valores grafico valvula gas
        RangoYValvGas = [0 1];
        
        %Valores grafico valvula queroseno
        RangoYValvQuero = [0 1];
        
        %Valores grafico presion
        RangoYPresion;
        ValorLineaPresion;
        EstiloLineaPresion; 
        
        %Valores grafico caudal combustible
        RangoYCombustible;
        ValorLineaCombustible;
        EstiloLineaCombustible; 
        
        %Valores grafico starter
        RangoYStarter;
        ValorLineaStarter;
        EstiloLineaStarter; 
        
        %Valores grafico Empuje
        RangoYEmpuje;
        ValorLineaEmpuje;
        EstiloLineaEmpuje; 
        
        %Variables error
        errorgeneral = 0;
        
    end
    
    methods (Access = private) 
        
        function salida = Char2StringArray(app,casilla)          
            %Coprobamos el tipo de informacion que hay en el contenedor de
            %texto y actuamos en funcion de ello
            %Si la casilla esta vacia escribiremos nan(vacio ene MATLAB)
            if isempty(casilla.Value)
                salida = nan;
            
            %Si la casilla tiene como valor nan, lo volveremos a escribir    
            elseif casilla.Value == "NaN" 
                salida = nan;
            
            else
                %Tratamos las cadenas de texto para que mantengan el
                %formato
                valores = split(casilla.Value,[" ",","]);
                
                for x = 1:1:height(valores)
                    A = erase(strcat(cell2mat(valores(x))),['"',"[","]"]);
                    salida(x) = convertCharsToStrings(A);                
               
                end
                
            end
            
        end        
        
        function ValoresPredeterminados(app)
            %Valores graficos globales
            RangoX = [-60,10];
          
            %Valores grafico rpm
            RangoYRPM = [0,150000];            
            ValorLineaRPM = [20000,32000,126000];
            EstiloLineaRPM = ["r--","b--","r--"];
            
            %Valores grafico temperatura
            RangoYTemp = [0,1000];
            ValorLineaTemp = [100,800];
            EstiloLineaTemp = ["b--","r--"];
            
            %Valores grafico potencia bomba
            RangoYPotBomb = [0,1];
            ValorLineaPotBomb = [nan];
            EstiloLineaPotBomb = [nan];            
            
            %Valores grafico throttle
            RangoYThrottle = [0,120];
            ValorLineaThrottle = [25,100];
            EstiloLineaThrottle = ["b--","r--"]; 
            
            %Valores grafico voltaje
            RangoYVoltaje = [0,10];
            ValorLineaVoltaje = [8.4,6];
            EstiloLineaVoltaje = ["b--","r--"];
            
            %Valores grafico valvula gas
            RangoYValvGas = [0 1];
            
            %Valores grafico valvula queroseno
            RangoYValvQuero = [0 1];
            
            %Valores grafico presion
            RangoYPresion = [0,4];
            ValorLineaPresion = [3.4];
            EstiloLineaPresion = ["r--"]; 
            
            %Valores grafico caudal combustible
            RangoYCombustible = [0,1];
            ValorLineaCombustible = [0.8,0.2];
            EstiloLineaCombustible = ["r--","b--"]; 
            
            %Valores grafico starter
            RangoYStarter = [0,10];
            ValorLineaStarter = [nan];
            EstiloLineaStarter = [nan]; 
            
            %Valores grafico Empuje
            RangoYEmpuje = [0,200];
            ValorLineaEmpuje = [150];
            EstiloLineaEmpuje = ["r--"]; 
             
            %Asignamos los valores predeterminados a las variables internas
            %y las mostramos en los campos de texto
            app.RangoX = RangoX;app.GENERAL.Value = mat2str(app.RangoX);
            
            app.RangoYRPM = RangoYRPM;app.A1.Value = mat2str(app.RangoYRPM);  
            app.ValorLineaRPM = ValorLineaRPM;app.A2.Value = mat2str(app.ValorLineaRPM);   
            app.EstiloLineaRPM = EstiloLineaRPM;app.A3.Value = mat2str(app.EstiloLineaRPM);     
            
            app.RangoYTemp = RangoYTemp;app.B1.Value = mat2str(app.RangoYTemp);   
            app.ValorLineaTemp = ValorLineaTemp;app.B2.Value = mat2str(app.ValorLineaTemp);   
            app.EstiloLineaTemp = EstiloLineaTemp;app.B3.Value = mat2str(app.EstiloLineaTemp);
            
            app.RangoYPotBomb = RangoYPotBomb;app.C1.Value = mat2str(app.RangoYPotBomb);   
            app.ValorLineaPotBomb = ValorLineaPotBomb;app.C2.Value = mat2str(app.ValorLineaPotBomb);   
            app.EstiloLineaPotBomb = EstiloLineaPotBomb;app.C3.Value = mat2str(app.EstiloLineaPotBomb); 
            
            app.RangoYThrottle = RangoYThrottle;app.D1.Value = mat2str(app.RangoYThrottle);   
            app.ValorLineaThrottle = ValorLineaThrottle;app.D2.Value = mat2str(app.ValorLineaThrottle);   
            app.EstiloLineaThrottle = EstiloLineaThrottle;app.D3.Value = mat2str(app.EstiloLineaThrottle); 
            
            app.RangoYVoltaje = RangoYVoltaje;app.E1.Value = mat2str(app.RangoYVoltaje);   
            app.ValorLineaVoltaje = ValorLineaVoltaje;app.E2.Value = mat2str(app.ValorLineaVoltaje);   
            app.EstiloLineaVoltaje = EstiloLineaVoltaje;app.E3.Value = mat2str(app.EstiloLineaVoltaje);
            
            app.RangoYPresion = RangoYPresion;app.F1.Value = mat2str(app.RangoYPresion);   
            app.ValorLineaPresion = ValorLineaPresion;app.F2.Value = mat2str(app.ValorLineaPresion);   
            app.EstiloLineaPresion = EstiloLineaPresion;app.F3.Value = mat2str(app.EstiloLineaPresion);
            
            app.RangoYCombustible = RangoYCombustible;app.G1.Value = mat2str(app.RangoYCombustible);   
            app.ValorLineaCombustible = ValorLineaCombustible;app.G2.Value = mat2str(app.ValorLineaCombustible);   
            app.EstiloLineaCombustible = EstiloLineaCombustible;app.G3.Value = mat2str(app.EstiloLineaCombustible);
            
            app.RangoYStarter = RangoYStarter;app.H1.Value = mat2str(app.RangoYStarter);   
            app.ValorLineaStarter = ValorLineaStarter;app.H2.Value = mat2str(app.ValorLineaStarter);   
            app.EstiloLineaStarter = EstiloLineaStarter;app.H3.Value = mat2str(app.EstiloLineaStarter);
            
            app.RangoYEmpuje = RangoYEmpuje;app.I1.Value = mat2str(app.RangoYEmpuje);   
            app.ValorLineaEmpuje = ValorLineaEmpuje;app.I2.Value = mat2str(app.ValorLineaEmpuje);   
            app.EstiloLineaEmpuje = EstiloLineaEmpuje;app.I3.Value = mat2str(app.EstiloLineaEmpuje);
            
            %Avisamos que se ha cargado el programa
            uialert(app.UIFigure,"Configuración predeterminada cargada. Para guardar la configuración hacer click en el bóton Guardar.","Aviso","Icon","warning");
        
        end
            
        function [RangoY,ValorLinea,EstiloLinea]=LeeConfigGrafico(app,casilla1,casilla2,casilla3,texto1,texto2)
            %Extraemos los datos de los campos de texto
            RangoY = str2double(split(erase(casilla1.Value,["[","]"]),[" ",","]))';
            ValorLinea = str2double(split(erase(casilla2.Value,["[","]"]),[" ",","]))';
            EstiloLinea = Char2StringArray(app,casilla3);
            
            %Comprobamos que los valores de lineas auxiliares y estilo
            %lineqas auxiliares tengan la misma cantidad de datos y que
            %esos datos sean del mismo tipo
            error1 = ((length(ValorLinea) ~= length(EstiloLinea))|...
                ((isnan(ValorLinea))&(class(EstiloLinea)~="double"))|...
                ((~isnan(ValorLinea))&(class(EstiloLinea)=="double")));
            
            %Comprobamos que Rango eje Y tenga dos valores
            error2 = length(RangoY)~=2;
            
            %Ilustramos el error 1
            if error1 == 1
                uialert(app.UIFigure,texto1,"Error","Icon","error");
                casilla2.BackgroundColor = [1,0.7,0.7];
                casilla3.BackgroundColor = [1,0.7,0.7];
                app.errorgeneral = 1;
            
            else
                casilla2.BackgroundColor = [1,1,1];
                casilla3.BackgroundColor = [1,1,1];                
            
            end
            
            %Ilustramos el error 2
            if error2 == 1
                uialert(app.UIFigure,texto2,"Error","Icon","error");
                casilla1.BackgroundColor = [1,0.7,0.7];
                app.errorgeneral = 1;
            
            else
                casilla1.BackgroundColor = [1,1,1];               
            
            end  
            
        end
                    
        function GuardaDatos(app)
            %Cargamos la configuración de los gráficos de las
            %electroválvulas, esta no varía porque los valores de estas
            %gráficas son binarios
            RangoYValvGas = app.RangoYValvGas;
            RangoYValvQuero = app.RangoYValvQuero;
            
            %Reañizamos una comprobacion de los valores introducidos en
            %Rango de tiempo, esta es similar a la vista en la función
            %"LeeConfigGrafico"
            app.errorgeneral = 0;
            error1 = 0;
            error2 = 0;
            
            %Leemos valores de los campos de texto
            RangoX = str2double(split(erase(app.GENERAL.Value,["[","]"]),[" ",","]))';
            error2 = length(RangoX)~=2;
            
            if error2 == 1
                uialert(app.UIFigure,"El número de elementosdel rango del eje X de los gráficos debe ser 2 (ordenados de menor a mayor)","Error","Icon","error");
                app.GENERAL.BackgroundColor = [1,0.7,0.7];
                app.errorgeneral = 1;
                
            else
                app.GENERAL.BackgroundColor = [1,1,1];               
            
            end  
            
            %Leemos los datos de resto de los campos de texto
            [RangoYRPM,ValorLineaRPM,EstiloLineaRPM]=LeeConfigGrafico(app,app.A1,app.A2,app.A3,"El número de elementos o los formatos de las lineas del gráfico RPM no coinciden","El número de elementos del rango del eje Y del gráfico RPM debe ser 2 (ordenados de menor a mayor)");
            [RangoYTemp,ValorLineaTemp,EstiloLineaTemp]=LeeConfigGrafico(app,app.B1,app.B2,app.B3,"El número de elementos o los formatos de las lineas del gráfico Temperatura no coinciden","El número de elementos del rango del eje Y del gráfico RPM debe ser 2 (ordenados de menor a mayor)");           
            [RangoYPotBomb,ValorLineaPotBomb,EstiloLineaPotBomb]=LeeConfigGrafico(app,app.C1,app.C2,app.C3,"El número de elementos o los formatos de las lineas del gráfico Potencia Bomba no coinciden","El número de elementos del rango del eje Y del gráfico Potencia Bomba debe ser 2 (ordenados de menor a mayor)");
            [RangoYThrottle,ValorLineaThrottle,EstiloLineaThrottle]=LeeConfigGrafico(app,app.D1,app.D2,app.D3,"El número de elementos o los formatos de las lineas del gráfico Temperatura no coinciden","El número de elementos del rango del eje Y del gráfico RPM debe ser 2 (ordenados de menor a mayor)");
            [RangoYVoltaje,ValorLineaVoltaje,EstiloLineaVoltaje]=LeeConfigGrafico(app,app.E1,app.E2,app.E3,"El número de elementos o los formatos de las lineas del gráfico Voltaje no coinciden","El número de elementos del rango del eje Y del gráfico Voltaje debe ser 2 (ordenados de menor a mayor)");
            [RangoYPresion,ValorLineaPresion,EstiloLineaPresion]=LeeConfigGrafico(app,app.F1,app.F2,app.F3,"El número de elementos o los formatos de las lineas del gráfico Presión no coinciden","El número de elementos del rango del eje Y del gráfico Presión debe ser 2 (ordenados de menor a mayor)");
            [RangoYCombustible,ValorLineaCombustible,EstiloLineaCombustible]=LeeConfigGrafico(app,app.G1,app.G2,app.G3,"El número de elementos o los formatos de las lineas del gráfico Caudal Combustible no coinciden","El número de elementos del rango del eje Y del gráfico Caudal Combustible debe ser 2 (ordenados de menor a mayor)");
            [RangoYStarter,ValorLineaStarter,EstiloLineaStarter]=LeeConfigGrafico(app,app.H1,app.H2,app.H3,"El número de elementos de las lineas o los formatos del gráfico Starter no coinciden","El número de elementos del rango del eje Y del gráfico Starter debe ser 2 (ordenados de menor a mayor)");
            [RangoYEmpuje,ValorLineaEmpuje,EstiloLineaEmpuje]=LeeConfigGrafico(app,app.I1,app.I2,app.I3,"El número de elementos de las lineas o los formatos del gráfico Empuje no coinciden","El número de elementos del rango del eje Y del gráfico Empuje debe ser 2 (ordenados de menor a mayor)");
            
            %Comprobamos si se ha detectado algún error
            if app.errorgeneral == 1     
            
            else
                %Si no se detectan errores se guarda la configuración
                save(fullfile(pwd,'Config.mat') ...
                    ,'RangoX' ...
                    ,'RangoYRPM','ValorLineaRPM','EstiloLineaRPM' ...
                    ,'RangoYTemp','ValorLineaTemp','EstiloLineaTemp' ...
                    ,'RangoYPotBomb','ValorLineaPotBomb','EstiloLineaPotBomb' ...
                    ,'RangoYThrottle','ValorLineaThrottle','EstiloLineaThrottle' ...
                    ,'RangoYVoltaje','ValorLineaVoltaje','EstiloLineaVoltaje' ...
                    ,'RangoYValvGas','RangoYValvQuero'...
                    ,'RangoYPresion','ValorLineaPresion','EstiloLineaPresion' ...
                    ,'RangoYCombustible','ValorLineaCombustible','EstiloLineaCombustible' ...
                    ,'RangoYStarter','ValorLineaStarter','EstiloLineaStarter' ...
                    ,'RangoYEmpuje','ValorLineaEmpuje','EstiloLineaEmpuje')
                
                %Avisamos de que se ha guardado la configuración
                uialert(app.UIFigure,"El guardado se ha realizado con éxito. Reinicie el programa para hacer efectivos los cambios.","Guardado completado","Icon","info")            
                
                %Cargamos la configuración de nuevo para que se muestre lo
                %que se ha guardado
                CargaDatos(app);
            
            end
            
        end
        
        function CargaDatos(app)
            %Cargamos el archivo de configuración
            load(fullfile(pwd,'Config.mat'));
            
            %Asignamos los datos de configuración a las variables internas
            %del programa y las representa en sus respectivos campos de
            %texto
            app.RangoX = RangoX;app.GENERAL.Value = mat2str(app.RangoX);
            
            app.RangoYRPM = RangoYRPM;app.A1.Value = mat2str(app.RangoYRPM);  
            app.ValorLineaRPM = ValorLineaRPM;app.A2.Value = mat2str(app.ValorLineaRPM);   
            app.EstiloLineaRPM = EstiloLineaRPM;app.A3.Value = mat2str(app.EstiloLineaRPM);    
            
            app.RangoYTemp = RangoYTemp;app.B1.Value = mat2str(app.RangoYTemp);   
            app.ValorLineaTemp = ValorLineaTemp;app.B2.Value = mat2str(app.ValorLineaTemp);   
            app.EstiloLineaTemp = EstiloLineaTemp;app.B3.Value = mat2str(app.EstiloLineaTemp);
            
            app.RangoYPotBomb = RangoYPotBomb;app.C1.Value = mat2str(app.RangoYPotBomb);   
            app.ValorLineaPotBomb = ValorLineaPotBomb;app.C2.Value = mat2str(app.ValorLineaPotBomb);   
            app.EstiloLineaPotBomb = EstiloLineaPotBomb;app.C3.Value = mat2str(app.EstiloLineaPotBomb); 
            
            app.RangoYThrottle = RangoYThrottle;app.D1.Value = mat2str(app.RangoYThrottle);   
            app.ValorLineaThrottle = ValorLineaThrottle;app.D2.Value = mat2str(app.ValorLineaThrottle);   
            app.EstiloLineaThrottle = EstiloLineaThrottle;app.D3.Value = mat2str(app.EstiloLineaThrottle); 
            
            app.RangoYVoltaje = RangoYVoltaje;app.E1.Value = mat2str(app.RangoYVoltaje);   
            app.ValorLineaVoltaje = ValorLineaVoltaje;app.E2.Value = mat2str(app.ValorLineaVoltaje);   
            app.EstiloLineaVoltaje = EstiloLineaVoltaje;app.E3.Value = mat2str(app.EstiloLineaVoltaje);
            
            app.RangoYPresion = RangoYPresion;app.F1.Value = mat2str(app.RangoYPresion);   
            app.ValorLineaPresion = ValorLineaPresion;app.F2.Value = mat2str(app.ValorLineaPresion);   
            app.EstiloLineaPresion = EstiloLineaPresion;app.F3.Value = mat2str(app.EstiloLineaPresion);
            
            app.RangoYCombustible = RangoYCombustible;app.G1.Value = mat2str(app.RangoYCombustible);   
            app.ValorLineaCombustible = ValorLineaCombustible;app.G2.Value = mat2str(app.ValorLineaCombustible);   
            app.EstiloLineaCombustible = EstiloLineaCombustible;app.G3.Value = mat2str(app.EstiloLineaCombustible);
            
            app.RangoYStarter = RangoYStarter;app.H1.Value = mat2str(app.RangoYStarter);   
            app.ValorLineaStarter = ValorLineaStarter;app.H2.Value = mat2str(app.ValorLineaStarter);   
            app.EstiloLineaStarter = EstiloLineaStarter;app.H3.Value = mat2str(app.EstiloLineaStarter);
            
            app.RangoYEmpuje = RangoYEmpuje;app.I1.Value = mat2str(app.RangoYEmpuje);   
            app.ValorLineaEmpuje = ValorLineaEmpuje;app.I2.Value = mat2str(app.ValorLineaEmpuje);   
            app.EstiloLineaEmpuje = EstiloLineaEmpuje;app.I3.Value = mat2str(app.EstiloLineaEmpuje);
            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Se intenta cargar la configuración guardada, si no se
            %consigue realizar esta acción automáticamente se carga la
            %configuración predeterminada y se guarda.
            try
                CargaDatos(app);
            
            catch
                uialert(app.UIFigure,"No se ha podido cargar el archivo de configuracion. Se cargarán los valores predeterminados.","Error","Icon","error");
                ValoresPredeterminados(app);
                GuardaDatos(app);
                
            end
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            %Activamos la función guardar datos
            GuardaDatos(app);
            
        end

        % Button pushed function: ValorespredeterminadosButton
        function ValorespredeterminadosButtonPushed(app, event)
            %   Confirmación para cargar los valores predeterminados
            switch uiconfirm(app.UIFigure,'¿Esta seguro de que quiere reestablecer la configuracion a la predeterminada?','Alerta','Options',{'Si','No'},"DefaultOption",2,"CancelOption",2,'Icon','warning')
                
                case 'Si'
                    ValoresPredeterminados(app);
            end
            
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            %   Confirmación para cerrar la ventana
            switch uiconfirm(app.UIFigure,'¿Desea salir de la configuración?','Alerta','Options',{'Si','No'},"DefaultOption",2,"CancelOption",2,'Icon','warning')
                
                case 'Si'
                    delete(app.UIFigure)
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %   Confirmación para cerrar la ventana
            switch uiconfirm(app.UIFigure,'¿Desea salir de la configuración?','Alerta','Options',{'Si','No'},"DefaultOption",2,"CancelOption",2,'Icon','warning')
                
                case 'Si'
                    delete(app.UIFigure)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GeneralPanel = uipanel(app.GridLayout);
            app.GeneralPanel.Title = 'General';
            app.GridLayout2 = uigridlayout(app.GeneralPanel);
            app.RangodetiempoLabel = uilabel(app.GridLayout2);
            app.RangodetiempoLabel.Text = 'Rango de tiempo';
            app.GENERAL = uieditfield(app.GridLayout2, 'text');
            app.GrficoRgimenGiroPanel = uipanel(app.GridLayout);
            app.GrficoRgimenGiroPanel.Title = 'Gráfico Régimen Giro';
            app.GridLayout3 = uigridlayout(app.GrficoRgimenGiroPanel);
            app.RangoejeYLabel = uilabel(app.GridLayout3);
            app.RangoejeYLabel.Text = 'Rango eje Y';
            app.A1 = uieditfield(app.GridLayout3, 'text');
            app.LineasauxiliaresLabel = uilabel(app.GridLayout3);
            app.LineasauxiliaresLabel.Text = 'Lineas auxiliares';
            app.A2 = uieditfield(app.GridLayout3, 'text');
            app.EstilolineasauxiliaresLabel = uilabel(app.GridLayout3);
            app.EstilolineasauxiliaresLabel.Text = 'Estilo lineas auxiliares';
            app.A3 = uieditfield(app.GridLayout3, 'text');
            app.GrficoTemperaturaPanel = uipanel(app.GridLayout);
            app.GrficoTemperaturaPanel.Title = 'Gráfico Temperatura';
            app.GridLayout3_2 = uigridlayout(app.GrficoTemperaturaPanel);
            app.RangoejeYLabel_2 = uilabel(app.GridLayout3_2);
            app.RangoejeYLabel_2.Text = 'Rango eje Y';
            app.B1 = uieditfield(app.GridLayout3_2, 'text');
            app.LineasauxiliaresLabel_2 = uilabel(app.GridLayout3_2);
            app.LineasauxiliaresLabel_2.Text = 'Lineas auxiliares';
            app.EstilolineasauxiliaresLabel_2 = uilabel(app.GridLayout3_2);
            app.EstilolineasauxiliaresLabel_2.Text = 'Estilo lineas auxiliares';
            app.B3 = uieditfield(app.GridLayout3_2, 'text');
            app.B2 = uieditfield(app.GridLayout3_2, 'text');
            app.GrficoPotenciaBombaPanel = uipanel(app.GridLayout);
            app.GrficoPotenciaBombaPanel.Title = 'Gráfico Potencia Bomba';
            app.GridLayout3_3 = uigridlayout(app.GrficoPotenciaBombaPanel);
            app.RangoejeYLabel_3 = uilabel(app.GridLayout3_3);
            app.RangoejeYLabel_3.Text = 'Rango eje Y';
            app.C1 = uieditfield(app.GridLayout3_3, 'text');
            app.LineasauxiliaresLabel_3 = uilabel(app.GridLayout3_3);
            app.LineasauxiliaresLabel_3.Text = 'Lineas auxiliares';
            app.C2 = uieditfield(app.GridLayout3_3, 'text');
            app.EstilolineasauxiliaresLabel_3 = uilabel(app.GridLayout3_3);
            app.EstilolineasauxiliaresLabel_3.Text = 'Estilo lineas auxiliares';
            app.C3 = uieditfield(app.GridLayout3_3, 'text');
            app.GrficoAceleradorPanel = uipanel(app.GridLayout);
            app.GrficoAceleradorPanel.Title = 'Gráfico Acelerador';
            app.GridLayout3_4 = uigridlayout(app.GrficoAceleradorPanel);
            app.RangoejeYLabel_4 = uilabel(app.GridLayout3_4);
            app.RangoejeYLabel_4.Text = 'Rango eje Y';
            app.D1 = uieditfield(app.GridLayout3_4, 'text');
            app.LineasauxiliaresLabel_4 = uilabel(app.GridLayout3_4);
            app.LineasauxiliaresLabel_4.Text = 'Lineas auxiliares';
            app.D2 = uieditfield(app.GridLayout3_4, 'text');
            app.EstilolineasauxiliaresLabel_4 = uilabel(app.GridLayout3_4);
            app.EstilolineasauxiliaresLabel_4.Text = 'Estilo lineas auxiliares';
            app.D3 = uieditfield(app.GridLayout3_4, 'text');
            app.GrficoVoltajeBateraPanel = uipanel(app.GridLayout);
            app.GrficoVoltajeBateraPanel.Title = 'Gráfico Voltaje Batería';
            app.GridLayout3_5 = uigridlayout(app.GrficoVoltajeBateraPanel);
            app.RangoejeYLabel_5 = uilabel(app.GridLayout3_5);
            app.RangoejeYLabel_5.Text = 'Rango eje Y';
            app.E1 = uieditfield(app.GridLayout3_5, 'text');
            app.LineasauxiliaresLabel_5 = uilabel(app.GridLayout3_5);
            app.LineasauxiliaresLabel_5.Text = 'Lineas auxiliares';
            app.E2 = uieditfield(app.GridLayout3_5, 'text');
            app.EstilolineasauxiliaresLabel_5 = uilabel(app.GridLayout3_5);
            app.EstilolineasauxiliaresLabel_5.Text = 'Estilo lineas auxiliares';
            app.E3 = uieditfield(app.GridLayout3_5, 'text');
            app.GuardarButton = uibutton(app.GridLayout, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.CerrarButton = uibutton(app.GridLayout, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.GrficoPresinPanel = uipanel(app.GridLayout);
            app.GrficoPresinPanel.Title = 'Gráfico Presión';
            app.GridLayout3_6 = uigridlayout(app.GrficoPresinPanel);
            app.RangoejeYLabel_6 = uilabel(app.GridLayout3_6);
            app.RangoejeYLabel_6.Text = 'Rango eje Y';
            app.F1 = uieditfield(app.GridLayout3_6, 'text');
            app.LineasauxiliaresLabel_6 = uilabel(app.GridLayout3_6);
            app.LineasauxiliaresLabel_6.Text = 'Lineas auxiliares';
            app.F2 = uieditfield(app.GridLayout3_6, 'text');
            app.EstilolineasauxiliaresLabel_6 = uilabel(app.GridLayout3_6);
            app.EstilolineasauxiliaresLabel_6.Text = 'Estilo lineas auxiliares';
            app.F3 = uieditfield(app.GridLayout3_6, 'text');
            app.GrficoCaudalCombustibleLquidoPanel = uipanel(app.GridLayout);
            app.GrficoCaudalCombustibleLquidoPanel.Title = 'Gráfico Caudal Combustible Líquido';
            app.GridLayout3_7 = uigridlayout(app.GrficoCaudalCombustibleLquidoPanel);
            app.RangoejeYLabel_7 = uilabel(app.GridLayout3_7);
            app.RangoejeYLabel_7.Text = 'Rango eje Y';
            app.G1 = uieditfield(app.GridLayout3_7, 'text');
            app.LineasauxiliaresLabel_7 = uilabel(app.GridLayout3_7);
            app.LineasauxiliaresLabel_7.Text = 'Lineas auxiliares';
            app.G2 = uieditfield(app.GridLayout3_7, 'text');
            app.EstilolineasauxiliaresLabel_7 = uilabel(app.GridLayout3_7);
            app.EstilolineasauxiliaresLabel_7.Text = 'Estilo lineas auxiliares';
            app.G3 = uieditfield(app.GridLayout3_7, 'text');
            app.GrficoVoltajeMotorArranquePanel = uipanel(app.GridLayout);
            app.GrficoVoltajeMotorArranquePanel.Title = 'Gráfico Voltaje Motor Arranque';
            app.GridLayout3_8 = uigridlayout(app.GrficoVoltajeMotorArranquePanel);
            app.RangoejeYLabel_8 = uilabel(app.GridLayout3_8);
            app.RangoejeYLabel_8.Text = 'Rango eje Y';
            app.H1 = uieditfield(app.GridLayout3_8, 'text');
            app.LineasauxiliaresLabel_8 = uilabel(app.GridLayout3_8);
            app.LineasauxiliaresLabel_8.Text = 'Lineas auxiliares';
            app.H2 = uieditfield(app.GridLayout3_8, 'text');
            app.EstilolineasauxiliaresLabel_8 = uilabel(app.GridLayout3_8);
            app.EstilolineasauxiliaresLabel_8.Text = 'Estilo lineas auxiliares';
            app.H3 = uieditfield(app.GridLayout3_8, 'text');
            app.ValorespredeterminadosButton = uibutton(app.GridLayout, 'push');
            app.ValorespredeterminadosButton.ButtonPushedFcn = createCallbackFcn(app, @ValorespredeterminadosButtonPushed, true);
            app.ValorespredeterminadosButton.Text = 'Valores predeterminados';
            app.AlintroducirmsdeunvalorhandesepararseporespaciosEj20120300Label = uilabel(app.GridLayout);
            app.AlintroducirmsdeunvalorhandesepararseporespaciosEj20120300Label.Interpreter = 'html';
            app.AlintroducirmsdeunvalorhandesepararseporespaciosEj20120300Label.Text = {'1- En el apartado "Rando de tiempo" han de introducir DOS valores numéricos.'; '2- En el apartado "Rango eje Y" han de introducirse DOS valores numéricos.'; '3- En el apartado "Lineas auxiliares" han de introducirse valores numéricos.'; '4- Los valores de "Estilo lineas auxiliares" han de introducirse siguiendo la filosofía de estilos'; 'de linea de Matlab. Ej: ["b--"] <a href="https://es.mathworks.com/help/matlab/ref/linespec.html">Más información</a>'; '5- Se debe introducir el mismo numero de valores en los apartados "Lineas auxiliares" y '; '"Estilo lineas auxiliares".'; '6- Al introducir más de un valor han de separarse por espacios. Ej: [20 120 300]'; ''};
            app.GrficoEmpujePanel = uipanel(app.GridLayout);
            app.GrficoEmpujePanel.Title = 'Gráfico Empuje';
            app.GridLayout3_9 = uigridlayout(app.GrficoEmpujePanel);
            app.RangoejeYLabel_9 = uilabel(app.GridLayout3_9);
            app.RangoejeYLabel_9.Text = 'Rango eje Y';
            app.I1 = uieditfield(app.GridLayout3_9, 'text');
            app.LineasauxiliaresLabel_9 = uilabel(app.GridLayout3_9);
            app.LineasauxiliaresLabel_9.Text = 'Lineas auxiliares';
            app.I2 = uieditfield(app.GridLayout3_9, 'text');
            app.EstilolineasauxiliaresLabel_9 = uilabel(app.GridLayout3_9);
            app.EstilolineasauxiliaresLabel_9.Text = 'Estilo lineas auxiliares';
            app.I3 = uieditfield(app.GridLayout3_9, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Configuracion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end